classdef Mechanics_of_Materials_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        IbeamLabel                      matlab.ui.control.Label
        TabGroup3                       matlab.ui.container.TabGroup
        IxxTab                          matlab.ui.container.Tab
        Ixx_value                       matlab.ui.control.Label
        IxxLabel                        matlab.ui.control.Label
        Ibeam_add                       matlab.ui.control.Button
        dmEditField                     matlab.ui.control.NumericEditField
        dmEditFieldLabel                matlab.ui.control.Label
        twmEditField                    matlab.ui.control.NumericEditField
        twmEditFieldLabel               matlab.ui.control.Label
        bfmEditField                    matlab.ui.control.NumericEditField
        bfmEditFieldLabel               matlab.ui.control.Label
        tfmEditField                    matlab.ui.control.NumericEditField
        tfmEditFieldLabel               matlab.ui.control.Label
        Image5                          matlab.ui.control.Image
        E_add                           matlab.ui.control.Button
        EGPaEditField                   matlab.ui.control.NumericEditField
        EGPaEditFieldLabel              matlab.ui.control.Label
        Unit_add                        matlab.ui.control.Button
        UnitsLengthForceMomentDropDown  matlab.ui.control.DropDown
        UnitsLengthForceMomentLabel     matlab.ui.control.Label
        LoadsMomentsLabel               matlab.ui.control.Label
        SupportsLabel                   matlab.ui.control.Label
        Beam_add                        matlab.ui.control.Button
        BeamLengthmEditField            matlab.ui.control.NumericEditField
        BeamLengthmEditFieldLabel       matlab.ui.control.Label
        TabGroup2                       matlab.ui.container.TabGroup
        PointLoadsTab                   matlab.ui.container.Tab
        PositionmEditField_2            matlab.ui.control.NumericEditField
        PositionmEditField_2Label       matlab.ui.control.Label
        Point_load_angle                matlab.ui.control.Button
        Point_load_up                   matlab.ui.control.Button
        Point_load_down                 matlab.ui.control.Button
        AngleDegEditField               matlab.ui.control.NumericEditField
        AngleDegEditFieldLabel          matlab.ui.control.Label
        MagnitudeNEditField             matlab.ui.control.NumericEditField
        MagnitudeNEditFieldLabel        matlab.ui.control.Label
        Image3                          matlab.ui.control.Image
        Image2                          matlab.ui.control.Image
        Image                           matlab.ui.control.Image
        DistributedLoadsTab             matlab.ui.container.Tab
        Unit_DisForce                   matlab.ui.control.Label
        distributed_equation            matlab.ui.control.EditField
        WxLabel                         matlab.ui.control.Label
        EndPosmEditField                matlab.ui.control.NumericEditField
        EndPosmEditFieldLabel           matlab.ui.control.Label
        StartPosmEditField              matlab.ui.control.NumericEditField
        StartPosmEditFieldLabel         matlab.ui.control.Label
        Dist_load_up                    matlab.ui.control.Button
        Dist_load_down                  matlab.ui.control.Button
        Image_2                         matlab.ui.control.Image
        Image3_2                        matlab.ui.control.Image
        MomentsTab                      matlab.ui.container.Tab
        Moment_CCW_im                   matlab.ui.control.Image
        Moment_CCW                      matlab.ui.control.Button
        Moment_CW_im                    matlab.ui.control.Image
        MagnitudeNmEditField            matlab.ui.control.NumericEditField
        MagnitudeNmEditFieldLabel       matlab.ui.control.Label
        PositionmEditField              matlab.ui.control.NumericEditField
        PositionmEditFieldLabel         matlab.ui.control.Label
        Moment_CW                       matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        PinTab                          matlab.ui.container.Tab
        Image4                          matlab.ui.control.Image
        PinPositionmEditField           matlab.ui.control.NumericEditField
        PinPositionmEditFieldLabel      matlab.ui.control.Label
        Pin_add                         matlab.ui.control.Button
        RollerTab                       matlab.ui.container.Tab
        Image4_2                        matlab.ui.control.Image
        Roller_add                      matlab.ui.control.Button
        RollerPositionmEditField        matlab.ui.control.NumericEditField
        RollerPositionmEditFieldLabel   matlab.ui.control.Label
        FixedTab                        matlab.ui.container.Tab
        Image4_3                        matlab.ui.control.Image
        Fixed_add                       matlab.ui.control.Button
        FixedPositionmEditField         matlab.ui.control.NumericEditField
        FixedPositionmEditFieldLabel    matlab.ui.control.Label
        CenterPanel                     matlab.ui.container.Panel
        DeflectionLabel                 matlab.ui.control.Label
        Deflection_value                matlab.ui.control.Label
        Deflection                      matlab.ui.control.Button
        DeflectionPositionmEditField    matlab.ui.control.NumericEditField
        DeflectionPositionmEditFieldLabel  matlab.ui.control.Label
        ClearButton                     matlab.ui.control.Button
        ForceExtremesPanel              matlab.ui.container.Panel
        MIN_B                           matlab.ui.control.Label
        MAX_B                           matlab.ui.control.Label
        MIN_S                           matlab.ui.control.Label
        MAX_S                           matlab.ui.control.Label
        MIN_N                           matlab.ui.control.Label
        MAX_N                           matlab.ui.control.Label
        MINLabel                        matlab.ui.control.Label
        MAXLabel                        matlab.ui.control.Label
        BendingMomentLabel              matlab.ui.control.Label
        ShearForceLabel                 matlab.ui.control.Label
        NormalForceLabel                matlab.ui.control.Label
        ReactionsPanel                  matlab.ui.container.Panel
        Roller_Mx                       matlab.ui.control.Label
        Pin_Mx                          matlab.ui.control.Label
        Roller_Fy                       matlab.ui.control.Label
        Pin_Fy                          matlab.ui.control.Label
        Roller_Fx                       matlab.ui.control.Label
        Pin_Fx                          matlab.ui.control.Label
        Roller_Pos                      matlab.ui.control.Label
        Pin_pos                         matlab.ui.control.Label
        Roller_sup                      matlab.ui.control.Label
        Pin_sup                         matlab.ui.control.Label
        Fixed_Mx                        matlab.ui.control.Label
        Fixed_Fy                        matlab.ui.control.Label
        Fixed_Fx                        matlab.ui.control.Label
        Fixed_pos                       matlab.ui.control.Label
        PositionLabel_2                 matlab.ui.control.Label
        Fixed_sup                       matlab.ui.control.Label
        React_MxLabel                   matlab.ui.control.Label
        React_YLabel                    matlab.ui.control.Label
        React_XLabel                    matlab.ui.control.Label
        NotSolvedLamp                   matlab.ui.control.Lamp
        NotSolvedLampLabel              matlab.ui.control.Label
        SolveButton                     matlab.ui.control.Button
        BeamAnalysisLabel               matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        RightPanel                      matlab.ui.container.Panel
        UIAxes_5                        matlab.ui.control.UIAxes
        UIAxes_4                        matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        %Units
        Length_Unit='m';
        Force_Unit='N';
        Moment_Unit='N.m';
        DisForce_Unit='N/m';
        E_Unit='GPa';
        I_Unit='m^4';
        %Beam length
        Beam_length=[];
        %Scale
        scale=[];
        %sigma
        Fx=[];
        Fy=[];
        Mo=[];
        %Counters
        Forcedowncount=1;
        Forceupcount=1;
        ForceAnglecount=1;
        DisForcedowncount=1;
        DisForceupcount=1;
        MomentCCWcount=1;
        MomentCWcount=1;
        Fc=0;
        Mc=0;
        Dc=0;
        %E
        E=[];
        %I
        I=[];
        %Force & Moments (from Supports)
        Fx_Pin=[];
        Fx_Pin_General=[];
        Fy_Pin=[];
        Fy_Pin_General=[];
        Fy_Pin_Moment_General=[];
        Fy_Pin_teta_General=[];
        Fy_Pin_v_General=[];
        Fy_Roller=[];
        Fy_Roller_General=[];
        Fy_Roller_Moment_General=[];
        Fy_Roller_teta_General=[];
        Fy_Roller_v_General=[];
        Fx_Fixed=[];
        Fx_Fixed_General=[];
        Fy_Fixed=[];
        Fy_Fixed_General=[];
        Fy_Fixed_Moment_General=[];
        Fy_Fixed_teta_General=[];
        Fy_Fixed_v_General=[];
        Mo_Fixed=[];
        Mo_Fixed_General=[];
        teta_Mo_Fixed_General=[];
        v_Mo_Fixed_General=[];
        %Force & Moments (from loads)
        Forcedowny=[0];
        Forcedowny_General={};
        Forceupy=[0];
        Forceupy_General={};
        ForceAngle_x=[0];
        ForceAngle_x_General={};
        ForceAngle_y=[0];
        ForceAngle_y_General={};
        DisForceupy=[0];
        DisForcedowny=[0];
        DisForceupy_General={};
        DisForcedowny_General={};
        MomentCCW=[0];
        MomentCCW_General={};
        MomentCW=[0];
        MomentCW_General={};
        MomentForcedowny=[0];
        MomentForcedowny_General={};
        MomentForceupy=[0];
        MomentForceupy_General={};
        MomentForceAngle=[0];
        MomentForceAngle_General={};
        MomentDisForceupy=[0];
        MomentDisForcedowny=[0];
        MomentDisForceupy_General={};
        MomentDisForcedowny_General={};
        tetaForcedowny=[0];
        tetaForcedowny_General={};
        vForcedowny=[0];
        vForcedowny_General={};
        tetaForceupy=[0];
        tetaForceupy_General={};
        vForceupy=[0];
        vForceupy_General={};
        tetaForceAngle=[0];
        tetaForceAngle_General={};
        vForceAngle=[0];
        vForceAngle_General={};
        tetaMomentCCW=[0];
        tetaMomentCCW_General={};
        vMomentCCW=[0];
        vMomentCCW_General={};
        tetaMomentCW=[0];
        tetaMomentCW_General={};
        vMomentCW=[0];
        vMomentCW_General={};
        tetaDisForcedowny=[0];
        tetaDisForcedowny_General={};
        vDisForcedowny=[0];
        vDisForcedowny_General={};
        tetaDisForceupy=[0];
        tetaDisForceupy_General={};
        vDisForceupy=[0];
        vDisForceupy_General={};         
        %Positions
        Pin_Position=[];
        Roller_Position=[];
        Fixed_Position=[];
        Forcedowny_Position=[];
        Forceupy_Position=[];
        ForceAngle_Position=[];
        DisForceupy_Start_Position=[];
        DisForceupy_End_Position=[];
        DisForcedowny_Start_Position=[];
        DisForcedowny_End_Position=[];
        DisForceupy_Total_Position=[];
        DisForcedowny_Total_Position=[];
        MomentCCW_Position=[];
        MomentCW_Position=[];
        %Deflection
        Deflection_Mat=[];
        %Plotting
        Beam=[];
        Support=[];
        Force=[];
        Moment=[];
        DisForce=[];
        Reactions=[];
        MFReaction=[];
        % on/off
        Pin_is_on=0;
        Roller_is_on=0;
        Fixed_is_on=0;
        
    end    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Unit_add
        function Unit_addButtonPushed(app, event)
              v=app.UnitsLengthForceMomentDropDown.Value;
              
             switch v
                 case 'm - N - N.m'
                      app.Length_Unit='m';
                      app.Force_Unit='N';
                      app.Moment_Unit='N.m';
                      app.DisForce_Unit='N/m';
                      app.E_Unit='GPa';
                      app.I_Unit='m^4';
                 case 'cm - N - N.cm'
                      app.Length_Unit='cm';
                      app.Force_Unit='N';
                      app.Moment_Unit='N.cm';
                      app.DisForce_Unit='N/cm';
                      app.E_Unit='GPa';
                      app.I_Unit='cm^4';                      
                case 'm - kN - kN.m'
                     app.Length_Unit='m';
                     app.Force_Unit='kN';
                     app.Moment_Unit='kN.m';
                     app.DisForce_Unit='kN/m';
                     app.E_Unit='GPa';
                     app.I_Unit='m^4';                                          
                case 'ft - lbf - lbf.ft'
                     app.Length_Unit='ft';
                     app.Force_Unit='lbf';
                     app.Moment_Unit='lbf.ft';
                     app.DisForce_Unit='lbf/ft';
                      app.E_Unit='10^6 psi';
                      app.I_Unit='ft^4';                     
                case 'in - lbf - lbf.in'
                     app.Length_Unit='in';
                     app.Force_Unit='lbf';
                     app.Moment_Unit='lbf.in';
                     app.DisForce_Unit='lbf/in';
                     app.E_Unit='10^6 psi';
                     app.I_Unit='in^4';                      
             end
             
            app.BeamLengthmEditFieldLabel.Text=['Beam Length (' app.Length_Unit ')'];
            app.PinPositionmEditFieldLabel.Text=['Pin Position (' app.Length_Unit ')'];
            app.RollerPositionmEditFieldLabel.Text=['Roller Position (' app.Length_Unit ')'];
            app.FixedPositionmEditFieldLabel.Text=['Fixed Position (' app.Length_Unit ')'];
            app.PositionmEditField_2Label.Text=['Position (' app.Length_Unit ')'];
            app.MagnitudeNEditFieldLabel.Text=['Magnitude (' app.Force_Unit ')'];
            app.StartPosmEditFieldLabel.Text=['Start Pos (' app.Length_Unit ')'];
            app.EndPosmEditFieldLabel.Text=['End Pos (' app.Length_Unit ')'];
            app.Unit_DisForce.Text=['(' app.DisForce_Unit ')'];
            app.PositionmEditFieldLabel.Text=['Position (' app.Length_Unit ')'];
            app.MagnitudeNmEditFieldLabel.Text=['Magnitude (' app.Moment_Unit ')'];
            app.EGPaEditFieldLabel.Text=['E (' app.E_Unit ')'];
            app.tfmEditFieldLabel.Text=['tf (' app.Length_Unit ')'];
            app.twmEditFieldLabel.Text=['tw (' app.Length_Unit ')'];
            app.bfmEditFieldLabel.Text=['bf (' app.Length_Unit ')'];
            app.dmEditFieldLabel.Text=['d (' app.Length_Unit ')'];
            app.DeflectionPositionmEditFieldLabel.Text=['Deflection Position (' app.Length_Unit ')'];
        end

        % Button pushed function: Beam_add
        function Beam_addPushed(app, event)
            app.Beam_length=app.BeamLengthmEditField.Value;
            app.scale=app.Beam_length/12;
            rectangle(app.UIAxes,'Position',[0,2*app.scale,app.Beam_length,app.scale],'FaceColor','b');
            hold(app.UIAxes,'on');
            xlim(app.UIAxes,[-1.5*app.scale,app.Beam_length+1.5*app.scale]);
            ylim(app.UIAxes,[-2*app.scale,7*app.scale]);
             
            zz=0:0.01:app.Beam_length;
        %%draw length axe
            text(app.UIAxes,0,-app.scale/5,num2str(0),'Color','black');
            text(app.UIAxes,app.Beam_length,-app.scale/5,[num2str(app.Beam_length) '(' app.Length_Unit ')'],'Color','black');        
             ww=zeros(1,app.Beam_length*100+1);
             plot(app.UIAxes,zz,ww,'k');
             
        %%Define zero matrixes for cell arrays
            
            app.Forcedowny_General{1}=zeros(1,length(zz));
            app.Forcedowny_General{2}=zeros(1,length(zz));
            
            app.Forceupy_General{1}=zeros(1,length(zz));
            app.Forceupy_General{2}=zeros(1,length(zz));
            
            app.ForceAngle_x_General{1}=zeros(1,length(zz));
            app.ForceAngle_x_General{2}=zeros(1,length(zz));
            
            app.ForceAngle_y_General{1}=zeros(1,length(zz));
            app.ForceAngle_y_General{2}=zeros(1,length(zz));
            
            app.DisForceupy_General{1}=zeros(1,length(zz));
            app.DisForceupy_General{2}=zeros(1,length(zz));
            
            app.DisForcedowny_General{1}=zeros(1,length(zz));
            app.DisForcedowny_General{2}=zeros(1,length(zz));            
            
            app.MomentCCW_General{1}=zeros(1,length(zz));
            app.MomentCCW_General{2}=zeros(1,length(zz));
            
            app.MomentCW_General{1}=zeros(1,length(zz));
            app.MomentCW_General{2}=zeros(1,length(zz));
            
            app.MomentForcedowny_General{1}=zeros(1,length(zz));
            app.MomentForcedowny_General{2}=zeros(1,length(zz));
            
            app.MomentForceupy_General{1}=zeros(1,length(zz));
            app.MomentForceupy_General{2}=zeros(1,length(zz));
            
            app.MomentDisForceupy_General{1}=zeros(1,length(zz));
            app.MomentDisForceupy_General{2}=zeros(1,length(zz));
            
            app.MomentForceAngle_General{1}=zeros(1,length(zz));
            app.MomentForceAngle_General{2}=zeros(1,length(zz));
            
            app.MomentDisForcedowny_General{1}=zeros(1,length(zz));
            app.MomentDisForcedowny_General{2}=zeros(1,length(zz));

            app.tetaForcedowny_General{1}=zeros(1,length(zz));
            app.tetaForcedowny_General{2}=zeros(1,length(zz));

            app.vForcedowny_General{1}=zeros(1,length(zz));
            app.vForcedowny_General{2}=zeros(1,length(zz));

            app.tetaForceupy_General{1}=zeros(1,length(zz));
            app.tetaForceupy_General{2}=zeros(1,length(zz));

            app.vForceupy_General{1}=zeros(1,length(zz));
            app.vForceupy_General{2}=zeros(1,length(zz));

            app.tetaForceAngle_General{1}=zeros(1,length(zz));
            app.tetaForceAngle_General{2}=zeros(1,length(zz));

            app.vForceAngle_General{1}=zeros(1,length(zz));
            app.vForceAngle_General{2}=zeros(1,length(zz));

            app.tetaDisForcedowny_General{1}=zeros(1,length(zz));
            app.tetaDisForcedowny_General{2}=zeros(1,length(zz));

            app.vDisForcedowny_General{1}=zeros(1,length(zz));
            app.vDisForcedowny_General{2}=zeros(1,length(zz)); 

            app.tetaDisForceupy_General{1}=zeros(1,length(zz));
            app.tetaDisForceupy_General{2}=zeros(1,length(zz));

            app.vDisForceupy_General{1}=zeros(1,length(zz));
            app.vDisForceupy_General{2}=zeros(1,length(zz)); 

            app.tetaMomentCCW_General{1}=zeros(1,length(zz));
            app.tetaMomentCCW_General{2}=zeros(1,length(zz));            

            app.vMomentCCW_General{1}=zeros(1,length(zz));
            app.vMomentCCW_General{2}=zeros(1,length(zz)); 

            app.tetaMomentCW_General{1}=zeros(1,length(zz));
            app.tetaMomentCW_General{2}=zeros(1,length(zz));            

            app.vMomentCW_General{1}=zeros(1,length(zz));
            app.vMomentCW_General{2}=zeros(1,length(zz));

        end

        % Button pushed function: E_add
        function E_addButtonPushed(app, event)
            app.E=app.EGPaEditField.Value;
            switch app.Moment_Unit
                case 'N.m'
                    app.E=10^9*app.E;
                case 'N.cm'
                    app.E=10^5*app.E;                    
                case 'kN.m'
                    app.E=10^6*app.E;                    
                case 'lbf.ft'
                    app.E=144*10^6*app.E;
                case 'lbf.in'
                    app.E=10^6*app.E;
            end
        end

        % Button pushed function: Ibeam_add
        function Ibeam_addButtonPushed(app, event)
            format short
            tf=app.tfmEditField.Value;
            bf=app.bfmEditField.Value;
            tw=app.twmEditField.Value;
            d=app.dmEditField.Value;
            I1=1/12*(bf)*(tf)^3+bf*tf*(d/2-tf/2)^2;
            I2=1/12*tw*(d-2*tf)^3;
            app.I=2*I1+I2;

            app.Ixx_value.Text=[num2str(app.I),'',app.I_Unit];

        end

        % Button pushed function: Pin_add
        function Pin_addPushed(app, event)
            app.Pin_Position=app.PinPositionmEditField.Value;
            s=app.scale;
            triangle(app.UIAxes,s,app.Pin_Position-0.5*s,s);
            circle(app.UIAxes,app.Pin_Position,2*s,0.2*s,'y');
            text(app.UIAxes,app.Pin_Position,-s/5,num2str(app.Pin_Position),'Color','black');
            
            app.Pin_is_on=1;            
        end

        % Button pushed function: Roller_add
        function Roller_addPushed(app, event)
            app.Roller_Position=app.RollerPositionmEditField.Value;
            s=app.scale;
            circle(app.UIAxes,app.Roller_Position,1.5*s,0.5*s,'y');
            text(app.UIAxes,app.Roller_Position,-s/5,num2str(app.Roller_Position),'Color','black');
            
            app.Roller_is_on=1;          
        end

        % Button pushed function: Fixed_add
        function Fixed_addPushed(app, event)
            app.Fixed_Position=app.FixedPositionmEditField.Value;
            s=app.scale;
            rectangle(app.UIAxes,'Position',[app.Fixed_Position-0.25*s,s,0.5*s,s],'FaceColor','y');
            text(app.UIAxes,app.Fixed_Position,-s/5,num2str(app.Fixed_Position),'Color','black');
            
            app.Fixed_is_on=1;            
        end

        % Button pushed function: Point_load_down
        function Point_load_downPushed(app, event)
            app.Forcedowny_Position(app.Forcedowncount)=app.PositionmEditField_2.Value;
            app.Forcedowny(app.Forcedowncount)=app.MagnitudeNEditField.Value;            
%___________Calculations
            app.MomentForcedowny(app.Forcedowncount)=app.Forcedowny(app.Forcedowncount)*app.Forcedowny_Position(app.Forcedowncount);
            syms x            
            %%effect_on_ShearForce
            syms downforce
            downforce=piecewise(x<=app.Forcedowny_Position(app.Forcedowncount),0,x>=app.Forcedowny_Position(app.Forcedowncount),app.Forcedowny(app.Forcedowncount));
            Forcedowny_Mat=subs(downforce,x,0:0.01:app.Beam_length);
            Forcedowny_Mat=double(Forcedowny_Mat);
            app.Forcedowny_General{app.Forcedowncount}=Forcedowny_Mat;
            %%effect_on_BendingMoment
            syms downforceMoment
            downforceMoment=piecewise(x<=app.Forcedowny_Position(app.Forcedowncount),0,x>=app.Forcedowny_Position(app.Forcedowncount),app.Forcedowny(app.Forcedowncount)*(x-app.Forcedowny_Position(app.Forcedowncount)));
            MomentForcedowny_Mat=subs(downforceMoment,x,0:0.01:app.Beam_length);
            MomentForcedowny_Mat=double(MomentForcedowny_Mat);
            app.MomentForcedowny_General{app.Forcedowncount}=MomentForcedowny_Mat;
            %%effect_on_teta
            syms EI_teta
            EI_teta=piecewise(x<=app.Forcedowny_Position(app.Forcedowncount),0,x>=app.Forcedowny_Position(app.Forcedowncount),app.Forcedowny(app.Forcedowncount)/2*(x-app.Forcedowny_Position(app.Forcedowncount))^2);
            tetaForcedowny_Mat=subs(EI_teta,x,0:0.01:app.Beam_length);
            tetaForcedowny_Mat=double(tetaForcedowny_Mat);
            tetaForcedowny_Mat=tetaForcedowny_Mat/(app.E*app.I);
            app.tetaForcedowny_General{app.Forcedowncount}=tetaForcedowny_Mat;
            %%effect_on_v
            syms EI_v
            EI_v=piecewise(x<=app.Forcedowny_Position(app.Forcedowncount),0,x>=app.Forcedowny_Position(app.Forcedowncount),app.Forcedowny(app.Forcedowncount)/6*(x-app.Forcedowny_Position(app.Forcedowncount))^3);
            vForcedowny_Mat=subs(EI_v,x,0:0.01:app.Beam_length);
            vForcedowny_Mat=double(vForcedowny_Mat);
            vForcedowny_Mat=vForcedowny_Mat/(app.E*app.I);
            app.vForcedowny_General{app.Forcedowncount}=vForcedowny_Mat;            
%___________Plotting
            s=app.scale;
            f=app.Beam_length/6;
            app.Force=quiver(app.UIAxes,app.Forcedowny_Position(app.Forcedowncount),3*s+f,0,-f^2,1/f,'r',"LineWidth",2,'MaxHeadSize',1.5);  
            text(app.UIAxes,app.Forcedowny_Position(app.Forcedowncount),3*s+f,[' ' num2str(app.Forcedowny(app.Forcedowncount)) ' ' app.Force_Unit],'color','red');
            text(app.UIAxes,app.Forcedowny_Position(app.Forcedowncount),-s/5,num2str(app.Forcedowny_Position(app.Forcedowncount)),'Color','black');
            
            app.Forcedowncount=app.Forcedowncount+1;
            app.Fc=app.Fc+1;
        end

        % Button pushed function: Point_load_up
        function Point_load_upPushed(app, event)
            app.Forceupy_Position(app.Forceupcount)=app.PositionmEditField_2.Value;
            app.Forceupy(app.Forceupcount)=app.MagnitudeNEditField.Value;
%___________Calculations
            app.MomentForceupy(app.Forceupcount)=app.Forceupy(app.Forceupcount)*app.Forceupy_Position(app.Forceupcount);            
            syms x
            %%effect_on_ShearForce
            syms upforce
            upforce=piecewise(x<=app.Forceupy_Position(app.Forceupcount),0,x>=app.Forceupy_Position(app.Forceupcount),app.Forceupy(app.Forceupcount));
            Forceupy_Mat=subs(upforce,x,0:0.01:app.Beam_length);
            Forceupy_Mat=double(Forceupy_Mat);
            app.Forceupy_General{app.Forceupcount}=Forceupy_Mat;
            %%effect_on_BendingMoment
            syms upforceMoment
            upforceMoment=piecewise(x<=app.Forceupy_Position(app.Forceupcount),0,x>=app.Forceupy_Position(app.Forceupcount),app.Forceupy(app.Forceupcount)*(x-app.Forceupy_Position(app.Forceupcount)));
            MomentForceupy_Mat=subs(upforceMoment,x,0:0.01:app.Beam_length);
            MomentForceupy_Mat=double(MomentForceupy_Mat);
            app.MomentForceupy_General{app.Forceupcount}=MomentForceupy_Mat;
            %%effect_on_teta
            syms EI_teta
            EI_teta=piecewise(x<=app.Forceupy_Position(app.Forceupcount),0,x>=app.Forceupy_Position(app.Forceupcount),app.Forceupy(app.Forceupcount)/2*(x-app.Forceupy_Position(app.Forceupcount))^2);
            tetaForceupy_Mat=subs(EI_teta,x,0:0.01:app.Beam_length);
            tetaForceupy_Mat=double(tetaForceupy_Mat);
            tetaForceupy_Mat=tetaForceupy_Mat/(app.E*app.I);
            app.tetaForceupy_General{app.Forceupcount}=tetaForceupy_Mat;
            %%effect_on_v
            syms EI_v
            EI_v=piecewise(x<=app.Forceupy_Position(app.Forceupcount),0,x>=app.Forceupy_Position(app.Forceupcount),app.Forceupy(app.Forceupcount)/6*(x-app.Forceupy_Position(app.Forceupcount))^3);
            vForceupy_Mat=subs(EI_v,x,0:0.01:app.Beam_length);
            vForceupy_Mat=double(vForceupy_Mat);
            vForceupy_Mat=vForceupy_Mat/(app.E*app.I);
            app.vForceupy_General{app.Forceupcount}=vForceupy_Mat;            
%___________Plotting
            s=app.scale;
            f=app.Beam_length/6;
            app.Force=quiver(app.UIAxes,app.Forceupy_Position(app.Forceupcount),3*s,0,f^2,1/f,'r',"LineWidth",2,'MaxHeadSize',1.5);
            text(app.UIAxes,app.Forceupy_Position(app.Forceupcount),3*s+f,[' '  num2str(app.Forceupy(app.Forceupcount)) ' ' app.Force_Unit],'color','red');
            text(app.UIAxes,app.Forceupy_Position(app.Forceupcount),-s/5,num2str(app.Forceupy_Position(app.Forceupcount)),'Color','black');
            
            app.Forceupcount=app.Forceupcount+1;
            app.Fc=app.Fc+1;
        end

        % Button pushed function: Point_load_angle
        function Point_load_anglePushed(app, event)
            app.ForceAngle_Position(app.ForceAnglecount)=app.PositionmEditField_2.Value;
            angle=app.AngleDegEditField.Value;
%___________Calculations            
            app.ForceAngle_x(app.ForceAnglecount)=(app.MagnitudeNEditField.Value)*cosd(angle);
            app.ForceAngle_y(app.ForceAnglecount)=(app.MagnitudeNEditField.Value)*sind(angle);
            app.MomentForceAngle(app.ForceAnglecount)=(app.ForceAngle_y(app.ForceAnglecount))*app.ForceAngle_Position(app.ForceAnglecount);          
            syms x
            %%effect_on_NormalForce
            syms angleforcex
            angleforcex=piecewise(x<=app.ForceAngle_Position(app.ForceAnglecount),0,x>=app.ForceAngle_Position(app.ForceAnglecount),app.ForceAngle_x(app.ForceAnglecount));
            ForceAngle_x_Mat=subs(angleforcex,x,0:0.01:app.Beam_length);
            ForceAngle_x_Mat=double(ForceAngle_x_Mat);
            app.ForceAngle_x_General{app.ForceAnglecount}=ForceAngle_x_Mat;            
            %%effect_on_ShearForce
            syms angleforcey
            angleforcey=piecewise(x<=app.ForceAngle_Position(app.ForceAnglecount),0,x>=app.ForceAngle_Position(app.ForceAnglecount),app.ForceAngle_y(app.ForceAnglecount));
            ForceAngle_y_Mat=subs(angleforcey,x,0:0.01:app.Beam_length);
            ForceAngle_y_Mat=double(ForceAngle_y_Mat);
            app.ForceAngle_y_General{app.ForceAnglecount}=ForceAngle_y_Mat;
            %%effect_on_BendingMoment
            syms angleMoment
            angleMoment=piecewise(x<=app.ForceAngle_Position(app.ForceAnglecount),0,x>=app.ForceAngle_Position(app.ForceAnglecount),app.ForceAngle_y(app.ForceAnglecount)*(x-app.ForceAngle_Position(app.ForceAnglecount)));
            MomentForceAngle_Mat=subs(angleMoment,x,0:0.01:app.Beam_length);
            MomentForceAngle_Mat=double(MomentForceAngle_Mat);
            app.MomentForceAngle_General{app.ForceAnglecount}=MomentForceAngle_Mat;
            %%effect_on_teta
            syms EI_teta
            EI_teta=piecewise(x<=app.ForceAngle_Position(app.ForceAnglecount),0,x>=app.ForceAngle_Position(app.ForceAnglecount),app.ForceAngle_y(app.ForceAnglecount)/2*(x-app.ForceAngle_Position(app.ForceAnglecount))^2);
            tetaForceAngle_Mat=subs(EI_teta,x,0:0.01:app.Beam_length);
            tetaForceAngle_Mat=double(tetaForceAngle_Mat);
            tetaForceAngle_Mat=tetaForceAngle_Mat/(app.E*app.I);
            app.tetaForceAngle_General{app.ForceAnglecount}=tetaForceAngle_Mat;
            %%effect_on_v
            syms EI_v
            EI_v=piecewise(x<=app.ForceAngle_Position(app.ForceAnglecount),0,x>=app.ForceAngle_Position(app.ForceAnglecount),app.ForceAngle_y(app.ForceAnglecount)/6*(x-app.ForceAngle_Position(app.ForceAnglecount))^3);
            vForceAngle_Mat=subs(EI_v,x,0:0.01:app.Beam_length);
            vForceAngle_Mat=double(vForceAngle_Mat);
            vForceAngle_Mat=vForceAngle_Mat/(app.E*app.I);
            app.vForceAngle_General{app.ForceAnglecount}=vForceAngle_Mat;

%___________Plotting
            s=app.scale;
            f=app.Beam_length/6;
            app.Force=quiver(app.UIAxes,app.ForceAngle_Position(app.ForceAnglecount),3*s,f*cosd(angle),f*sind(angle),'r',"LineWidth",2,'MaxHeadSize',1.5);
            text(app.UIAxes,app.ForceAngle_Position(app.ForceAnglecount)-0.85*s,3*s+f,[num2str(app.MagnitudeNEditField.Value) ' ' app.Force_Unit ' \theta=' num2str(angle)],'color','red');
            text(app.UIAxes,app.ForceAngle_Position(app.ForceAnglecount),-s/5,num2str(app.ForceAngle_Position(app.ForceAnglecount)),'Color','black');
            
            app.ForceAnglecount=app.ForceAnglecount+1;
            app.Fc=app.Fc+1;
        end

        % Button pushed function: Dist_load_down
        function Dist_load_downPushed(app, event)
            app.DisForcedowny_Start_Position(app.DisForcedowncount)=app.StartPosmEditField.Value;
            app.DisForcedowny_End_Position(app.DisForcedowncount)=app.EndPosmEditField.Value;
            syms x y z zz zzz;
            syms w(x);
            xx=app.DisForcedowny_Start_Position(app.DisForcedowncount):0.01:app.DisForcedowny_End_Position(app.DisForcedowncount);            
            w_equation=app.distributed_equation.Value;
            w(x)=str2sym(w_equation);
            
%_______________%Calculations
            
            app.DisForcedowny(app.DisForcedowncount)=int(w,x,app.DisForcedowny_Start_Position(app.DisForcedowncount),app.DisForcedowny_End_Position(app.DisForcedowncount));
            app.DisForcedowny_Total_Position(app.DisForcedowncount)=int(w*x,x,app.DisForcedowny_Start_Position(app.DisForcedowncount),app.DisForcedowny_End_Position(app.DisForcedowncount))/int(w,x,app.DisForcedowny_Start_Position(app.DisForcedowncount),app.DisForcedowny_End_Position(app.DisForcedowncount));
            app.MomentDisForcedowny(app.DisForcedowncount)=app.DisForcedowny(app.DisForcedowncount)*app.DisForcedowny_Total_Position(app.DisForcedowncount);
            %%effect_on_ShearForce
            syms disforce
            disforce=piecewise(app.DisForcedowny_Start_Position(app.DisForcedowncount)>=x,0,app.DisForcedowny_Start_Position(app.DisForcedowncount)<=x<=app.DisForcedowny_End_Position(app.DisForcedowncount),-int(w,x,app.DisForcedowny_Start_Position(app.DisForcedowncount),y),x>=app.DisForcedowny_End_Position(app.DisForcedowncount),-int(w,x,app.DisForcedowny_Start_Position(app.DisForcedowncount),app.DisForcedowny_End_Position(app.DisForcedowncount)));
            disforce=subs(disforce,x,y);
            Matrix_of_DisForce=subs(disforce,y,0:0.01:app.Beam_length);
            Matrix_of_DisForce=double(Matrix_of_DisForce);
            app.DisForcedowny_General{app.DisForcedowncount}=Matrix_of_DisForce;
            %%effect_on_BendingMoment
            syms momentdisforce
            x_bar=int(w*x,x,app.DisForcedowny_Start_Position(app.DisForcedowncount),app.DisForcedowny_End_Position(app.DisForcedowncount))/int(w,x,app.DisForcedowny_Start_Position(app.DisForcedowncount),app.DisForcedowny_End_Position(app.DisForcedowncount));
            momentdisforce=piecewise(app.DisForcedowny_Start_Position(app.DisForcedowncount)>=x,0,app.DisForcedowny_Start_Position(app.DisForcedowncount)<=x<=app.DisForcedowny_End_Position(app.DisForcedowncount),-int(int(w,x,app.DisForcedowny_Start_Position(app.DisForcedowncount),y),y,app.DisForcedowny_Start_Position(app.DisForcedowncount),z),x>=app.DisForcedowny_End_Position(app.DisForcedowncount),-int(w,x,app.DisForcedowny_Start_Position(app.DisForcedowncount),app.DisForcedowny_End_Position(app.DisForcedowncount))*(x-x_bar));
            momentdisforce=subs(momentdisforce,x,z);
            Matrix_of_MomentDisForce=subs(momentdisforce,z,0:0.01:app.Beam_length);
            Matrix_of_MomentDisForce=double(Matrix_of_MomentDisForce);
            app.MomentDisForcedowny_General{app.DisForcedowncount}=Matrix_of_MomentDisForce;
            %%effect_on_teta
            syms EI_teta
            EI_teta=piecewise(app.DisForcedowny_Start_Position(app.DisForcedowncount)>=x,0,app.DisForcedowny_Start_Position(app.DisForcedowncount)<=x<=app.DisForcedowny_End_Position(app.DisForcedowncount),-int(int(int(w,x,app.DisForcedowny_Start_Position(app.DisForcedowncount),y),y,app.DisForcedowny_Start_Position(app.DisForcedowncount),z),z,app.DisForcedowny_Start_Position(app.DisForcedowncount),zz),x>=app.DisForcedowny_End_Position(app.DisForcedowncount),-int(w,x,app.DisForcedowny_Start_Position(app.DisForcedowncount),app.DisForcedowny_End_Position(app.DisForcedowncount))/2*(x-x_bar)^2);
            EI_teta=subs(EI_teta,x,zz);
            Matrix_of_tetaDisForce=subs(EI_teta,zz,0:0.01:app.Beam_length);
            Matrix_of_tetaDisForce=double(Matrix_of_tetaDisForce);
            Matrix_of_tetaDisForce=Matrix_of_tetaDisForce/(app.E*app.I);
            app.tetaDisForcedowny_General{app.DisForcedowncount}=Matrix_of_tetaDisForce;
            %%effect_on_v
            syms EI_v
            EI_v=piecewise(app.DisForcedowny_Start_Position(app.DisForcedowncount)>=x,0,app.DisForcedowny_Start_Position(app.DisForcedowncount)<=x<=app.DisForcedowny_End_Position(app.DisForcedowncount),-int(int(int(int(w,x,app.DisForcedowny_Start_Position(app.DisForcedowncount),y),y,app.DisForcedowny_Start_Position(app.DisForcedowncount),z),z,app.DisForcedowny_Start_Position(app.DisForcedowncount),zz),zz,app.DisForcedowny_Start_Position(app.DisForcedowncount),zzz),x>=app.DisForcedowny_End_Position(app.DisForcedowncount),-int(w,x,app.DisForcedowny_Start_Position(app.DisForcedowncount),app.DisForcedowny_End_Position(app.DisForcedowncount))/6*(x-x_bar)^3);
            EI_v=subs(EI_v,x,zzz);
            Matrix_of_vDisForce=subs(EI_v,zzz,0:0.01:app.Beam_length);
            Matrix_of_vDisForce=double(Matrix_of_vDisForce);
            Matrix_of_vDisForce=Matrix_of_vDisForce/(app.E*app.I);
            app.vDisForcedowny_General{app.DisForcedowncount}=Matrix_of_vDisForce;
%_______________%Plotting
            s=app.scale;
            W=subs(w,x,xx);
            W=double(W);
            W=app.Beam_length*(W/max(W))/4;
            W=W+3*s;
            f1=3*s-W(1);
            f2=3*s-W(((end+1)/2+1)/2);
            f3=3*s-W((end+1)/2);
            f4=3*s-W(((end+1)/2+end)/2);
            f5=3*s-W(end);
            quiver(app.UIAxes,app.DisForcedowny_Start_Position(app.DisForcedowncount),W(1),0,f1,1/f1,'g',"LineWidth",2,'MaxHeadSize',0.5);
            quiver(app.UIAxes,(((app.DisForcedowny_Start_Position(app.DisForcedowncount)+app.DisForcedowny_End_Position(app.DisForcedowncount))/2)+app.DisForcedowny_Start_Position(app.DisForcedowncount))/2,W(((end+1)/2+1)/2),0,f2,1/f2,'g',"LineWidth",2,'MaxHeadSize',0.5);
            quiver(app.UIAxes,(app.DisForcedowny_Start_Position(app.DisForcedowncount)+app.DisForcedowny_End_Position(app.DisForcedowncount))/2,W((end+1)/2),0,f3,1/f3,'g',"LineWidth",2,'MaxHeadSize',0.5); 
            quiver(app.UIAxes,(((app.DisForcedowny_Start_Position(app.DisForcedowncount)+app.DisForcedowny_End_Position(app.DisForcedowncount))/2)+app.DisForcedowny_End_Position(app.DisForcedowncount))/2,W(((end+1)/2+end)/2),0,f4,1/f4,'g',"LineWidth",2,'MaxHeadSize',0.5);
            quiver(app.UIAxes,app.DisForcedowny_End_Position(app.DisForcedowncount),W(end),0,f5,1/f5,'g',"LineWidth",2,'MaxHeadSize',0.5);
            
            app.DisForce=plot(app.UIAxes,xx,W,'g',"LineWidth",2);
            text(app.UIAxes,(xx(end)+xx(1))/2,max(W)+0.5*s,['w(x)=' app.distributed_equation.Value ' ' app.DisForce_Unit],'color','green');
            text(app.UIAxes,app.DisForcedowny_Start_Position(app.DisForcedowncount),-s/5,num2str(app.DisForcedowny_Start_Position(app.DisForcedowncount)),'Color','black');
            text(app.UIAxes,app.DisForcedowny_End_Position(app.DisForcedowncount),-s/5,num2str(app.DisForcedowny_End_Position(app.DisForcedowncount)),'Color','black');
            
            app.DisForcedowncount=app.DisForcedowncount+1;
            app.Dc=app.Dc+1;
        end

        % Button pushed function: Dist_load_up
        function Dist_load_upButtonPushed(app, event)
            app. DisForceupy_Start_Position(app.DisForceupcount)=app.StartPosmEditField.Value;
            app. DisForceupy_End_Position(app.DisForceupcount)=app.EndPosmEditField.Value;
            syms x y z zz zzz;
            syms w(x);
            xx=app. DisForceupy_Start_Position(app.DisForceupcount):0.01:app. DisForceupy_End_Position(app.DisForceupcount);
            w_equation=app.distributed_equation.Value;
            w(x)=str2sym(w_equation);

%_______________%Calculations            
            
            app.DisForceupy(app.DisForceupcount)=int(w,x,app. DisForceupy_Start_Position(app.DisForceupcount),app. DisForceupy_End_Position(app.DisForceupcount));
            app.DisForceupy_Total_Position(app.DisForceupcount)=int(w*x,x,app. DisForceupy_Start_Position(app.DisForceupcount),app. DisForceupy_End_Position(app.DisForceupcount))/int(w,x,app. DisForceupy_Start_Position(app.DisForceupcount),app. DisForceupy_End_Position(app.DisForceupcount));
            app.MomentDisForceupy(app.DisForceupcount)=app.DisForceupy(app.DisForceupcount)*app.DisForceupy_Total_Position(app.DisForceupcount);
            %%effect_on_ShearForce
            syms disforce
            disforce=piecewise(app.DisForceupy_Start_Position(app.DisForceupcount)>=x,0,app.DisForceupy_Start_Position(app.DisForceupcount)<=x<=app.DisForceupy_End_Position(app.DisForceupcount),-int(w,x,app.DisForceupy_Start_Position(app.DisForceupcount),y),x>=app.DisForceupy_End_Position(app.DisForceupcount),-int(w,x,app.DisForceupy_Start_Position(app.DisForceupcount),app.DisForceupy_End_Position(app.DisForceupcount)));
            disforce=subs(disforce,x,y);
            Matrix_of_DisForce=subs(disforce,y,0:0.01:app.Beam_length);
            Matrix_of_DisForce=double(Matrix_of_DisForce);
            app.DisForceupy_General{app.DisForceupcount}=Matrix_of_DisForce;
            %%effect_on_BendingMoment
            syms momentdisforce
            x_bar=int(w*x,x,app.DisForceupy_Start_Position(app.DisForceupcount),app.DisForceupy_End_Position(app.DisForceupcount))/int(w,x,app.DisForceupy_Start_Position(app.DisForceupcount),app.DisForceupy_End_Position(app.DisForceupcount));
            momentdisforce=piecewise(app.DisForceupy_Start_Position(app.DisForceupcount)>=x,0,app.DisForceupy_Start_Position(app.DisForceupcount)<=x<=app.DisForceupy_End_Position(app.DisForceupcount),-int(int(w,x,app.DisForceupy_Start_Position(app.DisForceupcount),y),y,app.DisForceupy_Start_Position(app.DisForceupcount),z),x>=app.DisForceupy_End_Position(app.DisForceupcount),-int(w,x,app.DisForceupy_Start_Position(app.DisForceupcount),app.DisForceupy_End_Position(app.DisForceupcount))*(x-x_bar));
            momentdisforce=subs(momentdisforce,x,z);
            Matrix_of_MomentDisForce=subs(momentdisforce,z,0:0.01:app.Beam_length);
            Matrix_of_MomentDisForce=double(Matrix_of_MomentDisForce);
            app.MomentDisForceupy_General{app.DisForceupcount}=Matrix_of_MomentDisForce;
            %%effect_on_teta
            syms EI_teta
            EI_teta=piecewise(app.DisForceupy_Start_Position(app.DisForceupcount)>=x,0,app.DisForceupy_Start_Position(app.DisForceupcount)<=x<=app.DisForceupy_End_Position(app.DisForceupcount),-int(int(int(w,x,app.DisForceupy_Start_Position(app.DisForceupcount),y),y,app.DisForceupy_Start_Position(app.DisForceupcount),z),z,app.DisForceupy_Start_Position(app.DisForceupcount),zz),x>=app.DisForceupy_End_Position(app.DisForceupcount),-int(w,x,app.DisForceupy_Start_Position(app.DisForceupcount),app.DisForceupy_End_Position(app.DisForceupcount))/2*(x-x_bar)^2);
            EI_teta=subs(EI_teta,x,zz);
            Matrix_of_tetaDisForce=subs(EI_teta,zz,0:0.01:app.Beam_length);
            Matrix_of_tetaDisForce=double(Matrix_of_tetaDisForce);
            Matrix_of_tetaDisForce=Matrix_of_tetaDisForce/(app.E*app.I);
            app.tetaDisForceupy_General{app.DisForceupcount}=Matrix_of_tetaDisForce;
            %%effect_on_v
            syms EI_v
            EI_v=piecewise(app.DisForceupy_Start_Position(app.DisForceupcount)>=x,0,app.DisForceupy_Start_Position(app.DisForceupcount)<=x<=app.DisForceupy_End_Position(app.DisForceupcount),-int(int(int(int(w,x,app.DisForceupy_Start_Position(app.DisForceupcount),y),y,app.DisForceupy_Start_Position(app.DisForceupcount),z),z,app.DisForceupy_Start_Position(app.DisForceupcount),zz),zz,app.DisForceupy_Start_Position(app.DisForceupcount),zzz),x>=app.DisForceupy_End_Position(app.DisForceupcount),-int(w,x,app.DisForceupy_Start_Position(app.DisForceupcount),app.DisForceupy_End_Position(app.DisForceupcount))/6*(x-x_bar)^3);
            EI_v=subs(EI_v,x,zzz);
            Matrix_of_vDisForce=subs(EI_teta,zzz,0:0.01:app.Beam_length);
            Matrix_of_vDisForce=double(Matrix_of_vDisForce);
            Matrix_of_vDisForce=Matrix_of_vDisForce/(app.E*app.I);
            app.vDisForceupy_General{app.DisForceupcount}=Matrix_of_vDisForce;

%_______________%Plotting            
            s=app.scale;
            W=subs(w,x,xx);
            W=double(W);
            W=app.Beam_length*(W/max(W))/4;
            W=W+3*s;
            f1=W(1)-3*s;
            f2=W(((end+1)/2+1)/2)-3*s;
            f3=W((end+1)/2)-3*s;
            f4=W(((end+1)/2+end)/2)-3*s;
            f5=W(end)-3*s;
            quiver(app.UIAxes,app. DisForceupy_Start_Position(app.DisForceupcount),3*s,0,f1^2,1/f1,'g',"LineWidth",2,'MaxHeadSize',0.5);
            quiver(app.UIAxes,(((app. DisForceupy_Start_Position(app.DisForceupcount)+app. DisForceupy_End_Position(app.DisForceupcount))/2)+app. DisForceupy_Start_Position(app.DisForceupcount))/2,3*s,0,f2^2,1/f2,'g',"LineWidth",2,'MaxHeadSize',0.5);
            quiver(app.UIAxes,(app. DisForceupy_Start_Position(app.DisForceupcount)+app. DisForceupy_End_Position(app.DisForceupcount))/2,3*s,0,f3^2,1/f3,'g',"LineWidth",2,'MaxHeadSize',0.5);
            quiver(app.UIAxes,(((app. DisForceupy_Start_Position(app.DisForceupcount)+app. DisForceupy_End_Position(app.DisForceupcount))/2)+app. DisForceupy_End_Position(app.DisForceupcount))/2,3*s,0,f4^2,1/f4,'g',"LineWidth",2,'MaxHeadSize',0.5);
            quiver(app.UIAxes,app. DisForceupy_End_Position(app.DisForceupcount),3*s,0,f5^2,1/f5,'g',"LineWidth",2,'MaxHeadSize',0.5);
            
            app.DisForce=plot(app.UIAxes,xx,W,'g',"LineWidth",2);
            text(app.UIAxes,(xx(end)+xx(1))/2,max(W)+0.5*s,['w(x)=' app.distributed_equation.Value ' ' app.DisForce_Unit],'color','green');
            text(app.UIAxes,app.DisForceupy_Start_Position(app.DisForceupcount),-s/5,num2str(app.DisForceupy_Start_Position(app.DisForceupcount)),'Color','black');
            text(app.UIAxes,app.DisForceupy_End_Position(app.DisForceupcount),-s/5,num2str(app.DisForceupy_End_Position(app.DisForceupcount)),'Color','black');            
            
            app.DisForceupcount=app.DisForceupcount+1;
            app.Dc=app.Dc+1;
        end

        % Button pushed function: Moment_CCW
        function Moment_CCWButtonPushed(app, event)
            app.MomentCCW_Position(app.MomentCCWcount)=app.PositionmEditField.Value;
            app.MomentCCW(app.MomentCCWcount)=app.MagnitudeNmEditField.Value;
            
%___________Calculations  
            %%effect_on_BendingMoment
            syms x
            syms ccwmoment
            ccwmoment=piecewise(x<=app.MomentCCW_Position(app.MomentCCWcount),0,x>=app.MomentCCW_Position(app.MomentCCWcount),app.MomentCCW(app.MomentCCWcount));
            MomentCCW_Mat=subs(ccwmoment,x,0:0.01:app.Beam_length);
            MomentCCW_Mat=double(MomentCCW_Mat);
            app.MomentCCW_General{app.MomentCCWcount}=MomentCCW_Mat;
            %%effect_on_teta
            syms EI_teta
            EI_teta=piecewise(x<=app.MomentCCW_Position(app.MomentCCWcount),0,x>=app.MomentCCW_Position(app.MomentCCWcount),app.MomentCCW(app.MomentCCWcount)*(x-app.MomentCCW_Position(app.MomentCCWcount)));
            tetaMomentCCW_Mat=subs(EI_teta,x,0:0.01:app.Beam_length);
            tetaMomentCCW_Mat=double(tetaMomentCCW_Mat);
            tetaMomentCCW_Mat=tetaMomentCCW_Mat/(app.E*app.I);
            app.tetaMomentCCW_General{app.MomentCCWcount}=tetaMomentCCW_Mat;
            %%effect_on_v
            syms EI_v
            EI_v=piecewise(x<=app.MomentCCW_Position(app.MomentCCWcount),0,x>=app.MomentCCW_Position(app.MomentCCWcount),app.MomentCCW(app.MomentCCWcount)/2*(x-app.MomentCCW_Position(app.MomentCCWcount))^2);
            vMomentCCW_Mat=subs(EI_v,x,0:0.01:app.Beam_length);
            vMomentCCW_Mat=double(vMomentCCW_Mat);
            vMomentCCW_Mat=vMomentCCW_Mat/(app.E*app.I);
            app.vMomentCCW_General{app.MomentCCWcount}=vMomentCCW_Mat;             
%___________Plotting
            s=app.scale;
            m=app.Beam_length/16;
            Draw_moment(app.UIAxes,app.MomentCCW_Position(app.MomentCCWcount),2.5*s,m,1,'magenta');
            app.Moment=scatter(app.UIAxes,app.MomentCCW_Position(app.MomentCCWcount),2.5*s,'filled','MarkerFaceColor','magenta');
            text(app.UIAxes,app.MomentCCW_Position(app.MomentCCWcount)-0.5*s,3*s+m,[num2str(app.MomentCCW(app.MomentCCWcount)) ' ' app.Moment_Unit],'color','magenta');
            text(app.UIAxes,app.MomentCCW_Position(app.MomentCCWcount),-s/5,num2str(app.MomentCCW_Position(app.MomentCCWcount)),'Color','black');
            
            app.MomentCCWcount=app.MomentCCWcount+1; 
            app.Mc=app.Mc+1;
        end

        % Button pushed function: Moment_CW
        function Moment_CWButtonPushed(app, event)
            app.MomentCW_Position(app.MomentCWcount)=app.PositionmEditField.Value;
            app.MomentCW(app.MomentCWcount)=app.MagnitudeNmEditField.Value;
            
%___________Calculations             
            %%effect_on_BendingMoment
            syms x
            syms cwmoment
            cwmoment=piecewise(x<=app.MomentCW_Position(app.MomentCWcount),0,x>=app.MomentCW_Position(app.MomentCWcount),app.MomentCW(app.MomentCWcount));
            MomentCW_Mat=subs(cwmoment,x,0:0.01:app.Beam_length);
            MomentCW_Mat=double(MomentCW_Mat);
            app.MomentCW_General{app.MomentCWcount}=MomentCW_Mat;
            %%effect_on_teta
            syms EI_teta
            EI_teta=piecewise(x<=app.MomentCW_Position(app.MomentCWcount),0,x>=app.MomentCW_Position(app.MomentCWcount),app.MomentCW(app.MomentCWcount)*(x-app.MomentCW_Position(app.MomentCWcount)));
            tetaMomentCW_Mat=subs(EI_teta,x,0:0.01:app.Beam_length);
            tetaMomentCW_Mat=double(tetaMomentCW_Mat);
            tetaMomentCW_Mat=tetaMomentCW_Mat/(app.E*app.I);
            app.tetaMomentCW_General{app.MomentCWcount}=tetaMomentCW_Mat;
            %%effect_on_v
            syms EI_v
            EI_v=piecewise(x<=app.MomentCW_Position(app.MomentCWcount),0,x>=app.MomentCW_Position(app.MomentCWcount),app.MomentCW(app.MomentCWcount)/2*(x-app.MomentCW_Position(app.MomentCWcount))^2);
            vMomentCW_Mat=subs(EI_v,x,0:0.01:app.Beam_length);
            vMomentCW_Mat=double(vMomentCW_Mat);
            vMomentCW_Mat=vMomentCW_Mat/(app.E*app.I);
            app.vMomentCW_General{app.MomentCWcount}=vMomentCW_Mat;            
%___________Plotting
            s=app.scale;
            m=app.Beam_length/16;
            Draw_moment(app.UIAxes,app.MomentCW_Position(app.MomentCWcount),2.5*s,m,-1,'magenta');    
            text(app.UIAxes,app.MomentCW_Position(app.MomentCWcount)-0.5*s,3*s+m,[num2str(app.MomentCW(app.MomentCWcount)) ' ' app.Moment_Unit],'color','magenta');
            app.Moment=scatter(app.UIAxes,app.MomentCW_Position(app.MomentCWcount),2.5*s,'filled','MarkerFaceColor','magenta');
            text(app.UIAxes,app.MomentCW_Position(app.MomentCWcount),-s/5,num2str(app.MomentCW_Position(app.MomentCWcount)),'Color','black');
            
            app.MomentCWcount=app.MomentCWcount+1;   
            app.Mc=app.Mc+1;
        end

        % Button pushed function: SolveButton
        function SolveButtonPushed(app, event)
        %%support Conditions/Parameters
            
            Force_Pin_x=[0];
            Force_Pin_y=[0];
            MomentForce_Pin=[0];
            Force_Roller_y=[0];
            MomentForce_Roller=[0];
            Force_Fixed_x=[0];
            Force_Fixed_y=[0];
            Moment_Fixed=[0];
            MomentForce_Fixed=[0];

            if app.Pin_is_on==1
                
                syms ForcePinx ForcePiny
                Force_Pin_x=[ForcePinx];
                Force_Pin_y=[ForcePiny];
                MomentForce_Pin=[app.Pin_Position*ForcePiny];
                
            end
            if app.Roller_is_on==1
                
                syms ForceRollery
                Force_Roller_y=[ForceRollery];
                MomentForce_Roller=[app.Roller_Position*ForceRollery];
                
            end
            if app.Fixed_is_on==1
                
                syms ForceFixedx ForceFixedy MomentFixed
                Force_Fixed_x=[ForceFixedx];
                Force_Fixed_y=[ForceFixedy];
                Moment_Fixed=[MomentFixed];
                MomentForce_Fixed=[app.Fixed_Position*ForceFixedy];
                
            end
            
        %%Distibuted Forces convert to scaler
        
            app.DisForcedowny=double(app.DisForcedowny);
            app.MomentDisForcedowny=double(app.MomentDisForcedowny);
            
            app.DisForceupy=double(app.DisForceupy);
            app.MomentDisForceupy=double(app.MomentDisForceupy);
            
            
            %% IN STATIC CONDITION
            
%%%% FIXED________________________________________________________________________
            if  app.Pin_is_on==0 && app.Roller_is_on==0 && app.Fixed_is_on==1
                
        %%Solving equilibrium equations
               
                app.Fx=sum(app.ForceAngle_x)+Force_Fixed_x;
                app.Fy=sum(app.Forceupy)-sum(app.Forcedowny)+sum(app.ForceAngle_y)+sum(app.DisForceupy)-sum(app.DisForcedowny)+Force_Fixed_y;                        
                app.Mo=sum(app.MomentForceupy)-sum(app.MomentForcedowny)+sum(app.MomentForceAngle)+sum(app.MomentDisForceupy)-sum(app.MomentDisForcedowny)+sum(app.MomentCCW)-sum(app.MomentCW)+MomentForce_Fixed+Moment_Fixed;
                [app.Fx_Fixed,app.Fy_Fixed,app.Mo_Fixed]=solve(app.Fx==0,app.Fy==0,app.Mo==0,ForceFixedx,ForceFixedy,MomentFixed);
                app.Fx_Fixed=double(app.Fx_Fixed);
                app.Fy_Fixed=double(app.Fy_Fixed);
                app.Mo_Fixed=double(app.Mo_Fixed);
                
        %%Plot supports vectors
        
                s=app.scale;
                if app.Fx_Fixed > 0
                    app.Reactions=quiver(app.UIAxes,app.Fixed_Position-s,s,s^2,0,1/s,'color','#7E2F8E',"LineWidth",2,'MaxHeadSize',1);
                    text(app.UIAxes,app.Fixed_Position-1.8*s,0.8*s,[num2str(app.Fx_Fixed) ' ' app.Force_Unit],'color','#7E2F8E');
                elseif app.Fx_Fixed < 0
                    app.Reactions=quiver(app.UIAxes,app.Fixed_Position-0.1*s,s,-s^2,0,1/s,'color','#7E2F8E',"LineWidth",2,'MaxHeadSize',1);
                    text(app.UIAxes,app.Fixed_Position-1.8*s,0.8*s,[num2str(-app.Fx_Fixed) ' ' app.Force_Unit],'color','#7E2F8E');                    
                end
                
                if app.Fy_Fixed > 0
                    app.Reactions=quiver(app.UIAxes,app.Fixed_Position,0,0,s^2,1/s,'color','#7E2F8E',"LineWidth",2,'MaxHeadSize',1);
                    text(app.UIAxes,app.Fixed_Position+0.1*s,0.45*s,[num2str(app.Fy_Fixed) ' ' app.Force_Unit],'color','#7E2F8E');
                elseif app.Fy_Fixed < 0
                    app.Reactions=quiver(app.UIAxes,app.Fixed_Position,s,0,-s^2,1/s,'color','#7E2F8E',"LineWidth",2,'MaxHeadSize',1);
                    text(app.UIAxes,app.Fixed_Position+0.1*s,0.45*s,[num2str(-app.Fy_Fixed) ' ' app.Force_Unit],'color','#7E2F8E');
                end
                if app.Mo_Fixed > 0
                    
                    Draw_moment(app.UIAxes,app.Fixed_Position,s,0.6*s,1,'#7E2F8E');
                    app.MFReaction=scatter(app.UIAxes,app.Fixed_Position,s,'filled','MarkerFaceColor','#7E2F8E');
                    text(app.UIAxes,app.Fixed_Position+s,1.1*s,[num2str(app.Mo_Fixed) ' ' app.Moment_Unit],'color','#7E2F8E');
                elseif app.Mo_Fixed < 0
                    
                    Draw_moment(app.UIAxes,app.Fixed_Position,s,0.6*s,-1,'#7E2F8E');
                    app.MFReaction=scatter(app.UIAxes,app.Fixed_Position,s,'filled','MarkerFaceColor','#7E2F8E');
                    text(app.UIAxes,app.Fixed_Position+s,1.1*s,[num2str(-app.Mo_Fixed) ' ' app.Moment_Unit],'color','#7E2F8E');
                end
                                
        %Legend
                hold(app.UIAxes,"off");
                if app.Fc>0 && app.Dc>0 && app.Mc>0            
                    legend(app.UIAxes,[app.Force,app.DisForce,app.Moment,app.Reactions],{'Force','DisForce','Moment','Reactions'});
                elseif app.Fc>0 && app.Dc>0 && app.Mc==0
                    legend(app.UIAxes,[app.Force,app.DisForce,app.Reactions],{'Force','DisForce','Reactions'});
                elseif app.Fc>0 && app.Dc==0 && app.Mc>0
                    legend(app.UIAxes,[app.Force,app.Moment,app.Reactions],{'Force','Moment','Reactions'});
                elseif app.Fc==0 && app.Dc>0 && app.Mc>0
                    legend(app.UIAxes,[app.DisForce,app.Moment,app.Reactions],{'DisForce','Moment','Reactions'});
                elseif app.Fc>0 && app.Dc==0 && app.Mc==0
                    legend(app.UIAxes,[app.Force,app.Reactions],{'Force','Reactions'});
                elseif app.Fc==0 && app.Dc>0 && app.Mc==0
                    legend(app.UIAxes,[app.DisForce,app.Reactions],{'DisForce','Reactions'});
                elseif app.Fc==0 && app.Dc==0 && app.Mc>0
                    legend(app.UIAxes,[app.Moment,app.MFReaction],{'Moment','Reactions'});         
                end    
                
        %%Supports Effects
                
                syms x
                %%effect_on_ShearForce__________FX_Fixed
                syms fxfixed
                fxfixed=piecewise(x<=app.Fixed_Position,0,x>=app.Fixed_Position,app.Fx_Fixed);
                FX_Fixed_Mat=subs(fxfixed,x,0:0.01:app.Beam_length);
                FX_Fixed_Mat=double(FX_Fixed_Mat);
                app.Fx_Fixed_General=FX_Fixed_Mat;                
                %%effect_on_ShearForce__________FY_Fixed
                syms fyfixed
                fyfixed=piecewise(x<=app.Fixed_Position,0,x>=app.Fixed_Position,app.Fy_Fixed);
                FY_Fixed_Mat=subs(fyfixed,x,0:0.01:app.Beam_length);
                FY_Fixed_Mat=double(FY_Fixed_Mat);
                app.Fy_Fixed_General=FY_Fixed_Mat;
                %%effect_on_BendingMoment__________FY_Fixed
                syms fyfixedMoment
                fyfixedMoment=piecewise(x<=app.Fixed_Position,0,x>=app.Fixed_Position,app.Fy_Fixed*(x-app.Fixed_Position));
                FY_Fixed_Moment_Mat=subs(fyfixedMoment,x,0:0.01:app.Beam_length);
                FY_Fixed_Moment_Mat=double(FY_Fixed_Moment_Mat);
                app.Fy_Fixed_Moment_General=FY_Fixed_Moment_Mat;
                %%effect_on_teta__________FY_Fixed
                syms EI_teta_Fy
                EI_teta_Fy=piecewise(x<=app.Fixed_Position,0,x>=app.Fixed_Position,app.Fy_Fixed/2*(x-app.Fixed_Position)^2);
                FY_Fixed_teta_Mat=subs(EI_teta_Fy,x,0:0.01:app.Beam_length);
                FY_Fixed_teta_Mat=double(FY_Fixed_teta_Mat);
                FY_Fixed_teta_Mat=FY_Fixed_teta_Mat/(app.E*app.I);
                app.Fy_Fixed_teta_General=FY_Fixed_teta_Mat;
                %%effect_on_v__________FY_Fixed
                syms EI_v_Fy
                EI_v_Fy=piecewise(x<=app.Fixed_Position,0,x>=app.Fixed_Position,app.Fy_Fixed/6*(x-app.Fixed_Position)^3);
                FY_Fixed_v_Mat=subs(EI_v_Fy,x,0:0.01:app.Beam_length);
                FY_Fixed_v_Mat=double(FY_Fixed_v_Mat);
                FY_Fixed_v_Mat=FY_Fixed_v_Mat/(app.E*app.I);
                app.Fy_Fixed_v_General=FY_Fixed_v_Mat;                  
                %%effect_on_BendingMoment__________Mo_Fixed
                syms MoMoment
                MoMoment=piecewise(x<=app.Fixed_Position,0,x>=app.Fixed_Position,app.Mo_Fixed);
                MoMoment_Mat=subs(MoMoment,x,0:0.01:app.Beam_length);
                MoMoment_Mat=double(MoMoment_Mat);
                app.Mo_Fixed_General=MoMoment_Mat;
                %%effect_on_teta__________Mo_Fixed
                syms EI_teta_Mo
                EI_teta_Mo=piecewise(x<=app.Fixed_Position,0,x>=app.Fixed_Position,app.Mo_Fixed*(x-app.Fixed_Position));
                tetaMoMoment_Mat=subs(EI_teta_Mo,x,0:0.01:app.Beam_length);
                tetaMoMoment_Mat=double(tetaMoMoment_Mat);
                tetaMoMoment_Mat=tetaMoMoment_Mat/(app.E*app.I);
                app.teta_Mo_Fixed_General=tetaMoMoment_Mat;                
                %%effect_on_v__________Mo_Fixed
                syms EI_v_Mo
                EI_v_Mo=piecewise(x<=app.Fixed_Position,0,x>=app.Fixed_Position,app.Mo_Fixed/2*(x-app.Fixed_Position)^2);
                vMoMoment_Mat=subs(EI_v_Mo,x,0:0.01:app.Beam_length);
                vMoMoment_Mat=double(vMoMoment_Mat);
                vMoMoment_Mat=vMoMoment_Mat/(app.E*app.I);
                app.v_Mo_Fixed_General=vMoMoment_Mat;

        %%Plotting(Deflection, Shear Force, Bending Moment)
                
                xx=0:0.01:app.Beam_length;
                %%Normal Force
                NormalForce=-(sum(cell2mat(app.ForceAngle_x_General'))+app.Fx_Fixed_General);
                %%Shear Force
                ShearForce=sum(cell2mat(app.Forceupy_General'))-sum(cell2mat(app.Forcedowny_General'))+sum(cell2mat(app.ForceAngle_y_General'))-sum(cell2mat(app.DisForceupy_General'))+sum(cell2mat(app.DisForcedowny_General'))+app.Fy_Fixed_General;
                plot(app.UIAxes_3,xx,ShearForce,"LineWidth",2);
                grid(app.UIAxes_3,'on');
                title(app.UIAxes_3,'Shear Force');
                %%Bending Moment
                BendingMoment=sum(cell2mat(app.MomentForceupy_General'))-sum(cell2mat(app.MomentForcedowny_General'))+sum(cell2mat(app.MomentForceAngle_General'))-sum(cell2mat(app.MomentCCW_General'))+sum(cell2mat(app.MomentCW_General'))-sum(cell2mat(app.MomentDisForceupy_General'))+sum(cell2mat(app.MomentDisForcedowny_General'))+app.Fy_Fixed_Moment_General-app.Mo_Fixed_General;
                plot(app.UIAxes_4,xx,BendingMoment,"LineWidth",2);
                grid(app.UIAxes_4,'on');
                title(app.UIAxes_4,'Bending Moment');
                % Teta
                Teta=sum(cell2mat(app.tetaForceupy_General'))-sum(cell2mat(app.tetaForcedowny_General'))+sum(cell2mat(app.tetaForceAngle_General'))-sum(cell2mat(app.tetaMomentCCW_General'))+sum(cell2mat(app.tetaMomentCW_General'))-sum(cell2mat(app.tetaDisForceupy_General'))+sum(cell2mat(app.tetaDisForcedowny_General'))+app.Fy_Fixed_teta_General-app.teta_Mo_Fixed_General;
                % v(deflection)
                deflection=sum(cell2mat(app.vForceupy_General'))-sum(cell2mat(app.vForcedowny_General'))+sum(cell2mat(app.vForceAngle_General'))-sum(cell2mat(app.vMomentCCW_General'))+sum(cell2mat(app.vMomentCW_General'))-sum(cell2mat(app.vDisForceupy_General'))+sum(cell2mat(app.vDisForcedowny_General'))+app.Fy_Fixed_v_General-app.v_Mo_Fixed_General;
                
                syms C1 C2
                X=app.Fixed_Position;
                EQ1=Teta(100*X+1)+C1;
                EQ2=deflection(100*X+1)+C1*X+C2;
                [c1,c2]=solve(EQ1==0,EQ2==0,C1,C2);
                c1=double(c1);
                c2=double(c2);

                Teta=Teta+c1;
                deflection=deflection+c1*xx+c2;
                plot(app.UIAxes_5,xx,deflection,"LineWidth",2);
                grid(app.UIAxes_5,'on');
                title(app.UIAxes_5,'deflection'); 
                %Deflection
                app.Deflection_Mat=deflection;

        %%Force Extremes
                
                app.MAX_N.Text=[num2str(max(NormalForce)),' ',app.Force_Unit];
                app.MIN_N.Text=[num2str(min(NormalForce)),' ',app.Force_Unit];
                app.MAX_S.Text=[num2str(max(ShearForce)),' ',app.Force_Unit];
                app.MIN_S.Text=[num2str(min(ShearForce)),' ',app.Force_Unit];
                app.MAX_B.Text=[num2str(max(BendingMoment)),' ',app.Moment_Unit];
                app.MIN_B.Text=[num2str(min(BendingMoment)),' ',app.Moment_Unit];
                
        %%Reactions
        
                app.Fixed_sup.Text='Fixed';
                app.Fixed_pos.Text=[num2str(app.Fixed_Position), '(' app.Length_Unit ')'];
                app.Fixed_Fx.Text=[num2str(app.Fx_Fixed),' ',app.Force_Unit];
                app.Fixed_Fy.Text=[num2str(app.Fy_Fixed),' ',app.Force_Unit];
                app.Fixed_Mx.Text=[num2str(app.Mo_Fixed),' ',app.Moment_Unit];
                
                app.NotSolvedLamp.Color='g';
                app.NotSolvedLampLabel.Text='Solved';
                 
%%%% Pin_Roller_______________________________________________________________
        elseif  app.Pin_is_on==1 && app.Roller_is_on==1 && app.Fixed_is_on==0 
               
        %%Solving equilibrium equations
            
                app.Fx=sum(app.ForceAngle_x)+Force_Pin_x;
                app.Fy=sum(app.Forceupy)-sum(app.Forcedowny)+sum(app.ForceAngle_y)+sum(app.DisForceupy)-sum(app.DisForcedowny)+Force_Pin_y+Force_Roller_y;                        
                app.Mo=sum(app.MomentForceupy)-sum(app.MomentForcedowny)+sum(app.MomentForceAngle)+sum(app.MomentDisForceupy)-sum(app.MomentDisForcedowny)+sum(app.MomentCCW)-sum(app.MomentCW)+MomentForce_Pin+MomentForce_Roller;
                [app.Fx_Pin,app.Fy_Pin,app.Fy_Roller]=solve(app.Fx==0,app.Fy==0,app.Mo==0,ForcePinx,ForcePiny,ForceRollery);
                app.Fx_Pin=double(app.Fx_Pin);
                app.Fy_Pin=double(app.Fy_Pin);
                app.Fy_Roller=double(app.Fy_Roller);
                
        %%Plot supports vectors
                s=app.scale;
                if app.Fx_Pin > 0
                    app.Reactions=quiver(app.UIAxes,app.Pin_Position-s,s,s^2,0,1/s,'color','#7E2F8E',"LineWidth",2,'MaxHeadSize',1);
                    text(app.UIAxes,app.Pin_Position-1.8*s,0.8*s,[num2str(app.Fx_Pin) ' ' app.Force_Unit],'color','#7E2F8E');
                elseif app.Fx_Pin < 0
                    app.Reactions=quiver(app.UIAxes,app.Pin_Position-0.1*s,s,-s^2,0,1/s,'color','#7E2F8E',"LineWidth",2,'MaxHeadSize',1);
                    text(app.UIAxes,app.Pin_Position-1.8*s,0.8*s,[num2str(-app.Fx_Pin) ' ' app.Force_Unit],'color','#7E2F8E');                    
                end
                
                if app.Fy_Pin > 0
                    app.Reactions=quiver(app.UIAxes,app.Pin_Position,0,0,s^2,1/s,'color','#7E2F8E',"LineWidth",2,'MaxHeadSize',1);
                    text(app.UIAxes,app.Pin_Position+0.1*s,0.45*s,[num2str(app.Fy_Pin) ' ' app.Force_Unit],'color','#7E2F8E');
                elseif app.Fy_Pin < 0
                    app.Reactions=quiver(app.UIAxes,app.Pin_Position,s,0,-s^2,1/s,'color','#7E2F8E',"LineWidth",2,'MaxHeadSize',1);
                    text(app.UIAxes,app.Pin_Position+0.1*s,0.45*s,[num2str(-app.Fy_Pin) ' ' app.Force_Unit],'color','#7E2F8E');
                end
                if app.Fy_Roller > 0
                    app.Reactions=quiver(app.UIAxes,app.Roller_Position,0,0,s^2,1/s,'color','#7E2F8E',"LineWidth",2,'MaxHeadSize',1);
                    text(app.UIAxes,app.Roller_Position+0.2*s,0.45*s,[num2str(app.Fy_Roller) ' ' app.Force_Unit],'color','#7E2F8E');
                elseif app.Fy_Roller < 0
                    app.Reactions=quiver(app.UIAxes,app.Roller_Position,s,0,-s^2,1/s,'color','#7E2F8E',"LineWidth",2,'MaxHeadSize',1);
                    text(app.UIAxes,app.Roller_Position+0.2*s,0.45*s,[num2str(-app.Fy_Roller) ' ' app.Force_Unit],'color','#7E2F8E');
                end 
          
        %Legend
                hold(app.UIAxes,"off");
                if app.Fc>0 && app.Dc>0 && app.Mc>0            
                    legend(app.UIAxes,[app.Force,app.DisForce,app.Moment,app.Reactions],{'Force','DisForce','Moment','Reactions'});
                elseif app.Fc>0 && app.Dc>0 && app.Mc==0
                    legend(app.UIAxes,[app.Force,app.DisForce,app.Reactions],{'Force','DisForce','Reactions'});
                elseif app.Fc>0 && app.Dc==0 && app.Mc>0
                    legend(app.UIAxes,[app.Force,app.Moment,app.Reactions],{'Force','Moment','Reactions'});
                elseif app.Fc==0 && app.Dc>0 && app.Mc>0
                    legend(app.UIAxes,[app.DisForce,app.Moment,app.Reactions],{'DisForce','Moment','Reactions'});
                elseif app.Fc>0 && app.Dc==0 && app.Mc==0
                    legend(app.UIAxes,[app.Force,app.Reactions],{'Force','Reactions'});
                elseif app.Fc==0 && app.Dc>0 && app.Mc==0
                    legend(app.UIAxes,[app.DisForce,app.Reactions],{'DisForce','Reactions'});
                elseif app.Fc==0 && app.Dc==0 && app.Mc>0
                    legend(app.UIAxes,[app.Moment,app.Reactions],{'Moment','Reactions'});         
                end    
                
        %%Supports Effects
                
                syms x                
                %%effect_on_ShearForce__________FX_Pin
                syms fxpin
                fxpin=piecewise(x<=app.Pin_Position,0,x>=app.Pin_Position,app.Fx_Pin);
                FX_Pin_Mat=subs(fxpin,x,0:0.01:app.Beam_length);
                FX_Pin_Mat=double(FX_Pin_Mat);
                app.Fx_Pin_General=FX_Pin_Mat;               
                %%effect_on_ShearForce__________FY_Pin
                syms fypin
                fypin=piecewise(x<=app.Pin_Position,0,x>=app.Pin_Position,app.Fy_Pin);
                FY_Pin_Mat=subs(fypin,x,0:0.01:app.Beam_length);
                FY_Pin_Mat=double(FY_Pin_Mat);
                app.Fy_Pin_General=FY_Pin_Mat;
                %%effect_on_BendingMoment__________FY_Pin
                syms fypinMoment
                fypinMoment=piecewise(x<=app.Pin_Position,0,x>=app.Pin_Position,app.Fy_Pin*(x-app.Pin_Position));
                FY_Pin_Moment_Mat=subs(fypinMoment,x,0:0.01:app.Beam_length);
                FY_Pin_Moment_Mat=double(FY_Pin_Moment_Mat);
                app.Fy_Pin_Moment_General=FY_Pin_Moment_Mat; 
                %%effect_on_teta__________FY_Pin
                syms EI_teta_p
                EI_teta_p=piecewise(x<=app.Pin_Position,0,x>=app.Pin_Position,app.Fy_Pin/2*(x-app.Pin_Position)^2);
                FY_Pin_teta_Mat=subs(EI_teta_p,x,0:0.01:app.Beam_length);
                FY_Pin_teta_Mat=double(FY_Pin_teta_Mat);
                FY_Pin_teta_Mat=FY_Pin_teta_Mat/(app.E*app.I);
                app.Fy_Pin_teta_General=FY_Pin_teta_Mat; 
                %%effect_on_v__________FY_Pin
                syms EI_v_p
                EI_v_p=piecewise(x<=app.Pin_Position,0,x>=app.Pin_Position,app.Fy_Pin/6*(x-app.Pin_Position)^3);
                FY_Pin_v_Mat=subs(EI_v_p,x,0:0.01:app.Beam_length);
                FY_Pin_v_Mat=double(FY_Pin_v_Mat);
                FY_Pin_v_Mat=FY_Pin_v_Mat/(app.E*app.I);
                app.Fy_Pin_v_General=FY_Pin_v_Mat; 
                %%effect_on_ShearForce__________FY_Roller
                syms fyroller
                fyroller=piecewise(x<=app.Roller_Position,0,x>=app.Roller_Position,app.Fy_Roller);
                FY_Roller_Mat=subs(fyroller,x,0:0.01:app.Beam_length);
                FY_Roller_Mat=double(FY_Roller_Mat);
                app.Fy_Roller_General=FY_Roller_Mat;
                %%effect_on_BendingMoment__________FY_Roller
                syms fypinMoment
                fyrollerMoment=piecewise(x<=app.Roller_Position,0,x>=app.Roller_Position,app.Fy_Roller*(x-app.Roller_Position));
                FY_Roller_Moment_Mat=subs(fyrollerMoment,x,0:0.01:app.Beam_length);
                FY_Roller_Moment_Mat=double(FY_Roller_Moment_Mat);
                app.Fy_Roller_Moment_General=FY_Roller_Moment_Mat;
                %%effect_on_teta__________FY_Roller
                syms EI_teta_r
                EI_teta_r=piecewise(x<=app.Roller_Position,0,x>=app.Roller_Position,app.Fy_Roller/2*(x-app.Roller_Position)^2);
                FY_Roller_teta_Mat=subs(EI_teta_r,x,0:0.01:app.Beam_length);
                FY_Roller_teta_Mat=double(FY_Roller_teta_Mat);
                FY_Roller_teta_Mat=FY_Roller_teta_Mat/(app.E*app.I);
                app.Fy_Roller_teta_General=FY_Roller_teta_Mat;
                %%effect_on_v__________FY_Roller
                syms EI_v_r
                EI_v_r=piecewise(x<=app.Roller_Position,0,x>=app.Roller_Position,app.Fy_Roller/6*(x-app.Roller_Position)^3);
                FY_Roller_v_Mat=subs(EI_v_r,x,0:0.01:app.Beam_length);
                FY_Roller_v_Mat=double(FY_Roller_v_Mat);
                FY_Roller_v_Mat=FY_Roller_v_Mat/(app.E*app.I);
                app.Fy_Roller_v_General=FY_Roller_v_Mat;                

        %%Plotting(Deflection, Shear Force, Bending Moment)
                
                xx=0:0.01:app.Beam_length;
                %%Normal Force
                NormalForce=-(sum(cell2mat(app.ForceAngle_x_General'))+app.Fx_Pin_General);               
                %%Shear Force
                ShearForce=sum(cell2mat(app.Forceupy_General'))-sum(cell2mat(app.Forcedowny_General'))+sum(cell2mat(app.ForceAngle_y_General'))-sum(cell2mat(app.DisForceupy_General'))+sum(cell2mat(app.DisForcedowny_General'))+app.Fy_Pin_General+app.Fy_Roller_General;
                plot(app.UIAxes_3,xx,ShearForce,"LineWidth",2);
                grid(app.UIAxes_3,'on');
                title(app.UIAxes_3,'Shear Force');
                %%Bending Moment
                BendingMoment=sum(cell2mat(app.MomentForceupy_General'))-sum(cell2mat(app.MomentForcedowny_General'))+sum(cell2mat(app.MomentForceAngle_General'))-sum(cell2mat(app.MomentCCW_General'))+sum(cell2mat(app.MomentCW_General'))-sum(cell2mat(app.MomentDisForceupy_General'))+sum(cell2mat(app.MomentDisForcedowny_General'))+app.Fy_Pin_Moment_General+app.Fy_Roller_Moment_General;
                plot(app.UIAxes_4,xx,BendingMoment,"LineWidth",2);
                grid(app.UIAxes_4,'on');
                title(app.UIAxes_4,'Bending Moment');
                %%Teta
                Teta=sum(cell2mat(app.tetaForceupy_General'))-sum(cell2mat(app.tetaForcedowny_General'))+sum(cell2mat(app.tetaForceAngle_General'))-sum(cell2mat(app.tetaMomentCCW_General'))+sum(cell2mat(app.tetaMomentCW_General'))-sum(cell2mat(app.tetaDisForceupy_General'))+sum(cell2mat(app.tetaDisForcedowny_General'))+app.Fy_Pin_teta_General+app.Fy_Roller_teta_General;
                %%deflection
                deflection=sum(cell2mat(app.vForceupy_General'))-sum(cell2mat(app.vForcedowny_General'))+sum(cell2mat(app.vForceAngle_General'))-sum(cell2mat(app.vMomentCCW_General'))+sum(cell2mat(app.vMomentCW_General'))-sum(cell2mat(app.vDisForceupy_General'))+sum(cell2mat(app.vDisForcedowny_General'))+app.Fy_Pin_v_General+app.Fy_Roller_v_General;

                syms C1 C2
                X1=app.Pin_Position;
                X2=app.Roller_Position;
                EQ1=deflection(100*X1+1)+C1*X1+C2;
                EQ2=deflection(100*X2+1)+C1*X2+C2;
                [c1,c2]=solve(EQ1==0,EQ2==0,C1,C2);
                c1=double(c1);
                c2=double(c2);

                Teta=Teta+c1;
                deflection=deflection+c1*xx+c2;
                plot(app.UIAxes_5,xx,deflection,"LineWidth",2);
                grid(app.UIAxes_5,'on');
                title(app.UIAxes_5,'deflection');
                %Deflection
                app.Deflection_Mat=deflection;


        %%Force Extremes

                app.MAX_N.Text=[num2str(max(NormalForce)),' ',app.Force_Unit];
                app.MIN_N.Text=[num2str(min(NormalForce)),' ',app.Force_Unit];
                app.MAX_S.Text=[num2str(max(ShearForce)),' ',app.Force_Unit];
                app.MIN_S.Text=[num2str(min(ShearForce)),' ',app.Force_Unit];
                app.MAX_B.Text=[num2str(max(BendingMoment)),' ',app.Moment_Unit];
                app.MIN_B.Text=[num2str(min(BendingMoment)),' ',app.Moment_Unit];                

        %%Reactions
                app.Pin_sup.Text='Pin';
                app.Roller_sup.Text='Roller';
                app.Pin_pos.Text=[num2str(app.Pin_Position),'(' app.Length_Unit ')'];
                app.Roller_Pos.Text=[num2str(app.Roller_Position),'(' app.Length_Unit ')'];
                app.Pin_Fx.Text=[num2str(app.Fx_Pin),' ',app.Force_Unit];
                app.Roller_Fx.Text=['0 ',app.Force_Unit];
                app.Pin_Fy.Text=[num2str(app.Fy_Pin),' ',app.Force_Unit];
                app.Roller_Fy.Text=[num2str(app.Fy_Roller),' ',app.Force_Unit];
                app.Pin_Mx.Text=['0 ',app.Force_Unit];
                app.Roller_Mx.Text=['0 ',app.Force_Unit];
                app.Fixed_sup.Text=' ';
                app.Fixed_pos.Text=' ';
                app.Fixed_Fx.Text=' ';
                app.Fixed_Fy.Text=' ';
                app.Fixed_Mx.Text=' ';
                
                
                app.NotSolvedLamp.Color='g';
                app.NotSolvedLampLabel.Text='Solved';
                
%%%% NOT STATIC_______________________________________________________________                
            else                                
            
                app.NotSolvedLamp.Color='y';
                app.NotSolvedLampLabel.Text='Not Static';
                
            end            
        end

        % Button pushed function: Deflection
        function DeflectionButtonPushed(app, event)
            x=app.DeflectionPositionmEditField.Value;
            app.Deflection_value.Text=[num2str(app.Deflection_Mat(100*x+1)),app.Length_Unit];
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
        %Units
        app.Length_Unit='m';
        app.Force_Unit='N';
        app.Moment_Unit='N.m';
        app.DisForce_Unit='N/m';
        app.E_Unit='GPa';
        app.I_Unit='m^4';        
        %Beam length
        app.Beam_length=[];
        %Scale
        app.scale=[];
        %sigma
        app.Fx=[];
        app.Fy=[];
        app.Mo=[];
        %Counters
        app.Forcedowncount=1;
        app.Forceupcount=1;
        app.ForceAnglecount=1;
        app.DisForcedowncount=1;
        app.DisForceupcount=1;
        app.MomentCCWcount=1;
        app.MomentCWcount=1;
        app.Fc=0;
        app.Mc=0;
        app.Dc=0;
        %E
        app.E=[];
        %I
        app.I=[];
        %Force & Moments (from Supports)
        app.Fx_Pin=[];
        app.Fx_Pin_General=[];
        app.Fy_Pin=[];
        app.Fy_Pin_General=[];
        app.Fy_Pin_Moment_General=[];
        app.Fy_Pin_teta_General=[];
        app.Fy_Pin_v_General=[];
        app.Fy_Roller=[];
        app.Fy_Roller_General=[];
        app.Fy_Roller_Moment_General=[];
        app.Fy_Roller_teta_General=[];
        app.Fy_Roller_v_General=[];
        app.Fx_Fixed=[];
        app.Fx_Fixed_General=[];
        app.Fy_Fixed=[];
        app.Fy_Fixed_General=[];
        app.Fy_Fixed_Moment_General=[];
        app.Fy_Fixed_teta_General=[];
        app.Fy_Fixed_v_General=[];
        app.Mo_Fixed=[];
        app.Mo_Fixed_General=[];
        app.teta_Mo_Fixed_General=[];
        app.v_Mo_Fixed_General=[];
        %Force & Moments (from loads)
        app.Forcedowny=[0];
        app.Forcedowny_General={};
        app.Forceupy=[0];
        app.Forceupy_General={};
        app.ForceAngle_x=[0];
        app.ForceAngle_x_General={};
        app.ForceAngle_y=[0];
        app.ForceAngle_y_General={};
        app.DisForceupy=[0];
        app.DisForcedowny=[0];
        app.DisForceupy_General={};
        app.DisForcedowny_General={};
        app.MomentCCW=[0];
        app.MomentCCW_General={};
        app.MomentCW=[0];
        app.MomentCW_General={};
        app.MomentForcedowny=[0];
        app.MomentForcedowny_General={};
        app.MomentForceupy=[0];
        app.MomentForceupy_General={};
        app.MomentForceAngle=[0];
        app.MomentForceAngle_General={};
        app.MomentDisForceupy=[0];
        app.MomentDisForcedowny=[0];
        app.MomentDisForceupy_General={};
        app.MomentDisForcedowny_General={};
        app.tetaForcedowny=[0];
        app.tetaForcedowny_General={};
        app.vForcedowny=[0];
        app.vForcedowny_General={};
        app.tetaForceupy=[0];
        app.tetaForceupy_General={};
        app.vForceupy=[0];
        app.vForceupy_General={};
        app.tetaForceAngle=[0];
        app.tetaForceAngle_General={};
        app.vForceAngle=[0];
        app.vForceAngle_General={};
        app.tetaMomentCCW=[0];
        app.tetaMomentCCW_General={};
        app.vMomentCCW=[0];
        app.vMomentCCW_General={};
        app.tetaMomentCW=[0];
        app.tetaMomentCW_General={};
        app.vMomentCW=[0];
        app.vMomentCW_General={};
        app.tetaDisForcedowny=[0];
        app.tetaDisForcedowny_General={};
        app.vDisForcedowny=[0];
        app.vDisForcedowny_General={};
        app.tetaDisForceupy=[0];
        app.tetaDisForceupy_General={};
        app.vDisForceupy=[0];
        app.vDisForceupy_General={};         
        %Positions
        app.Pin_Position=[];
        app.Roller_Position=[];
        app.Fixed_Position=[];
        app.Forcedowny_Position=[];
        app.Forceupy_Position=[];
        app.ForceAngle_Position=[];
        app.DisForceupy_Start_Position=[];
        app.DisForceupy_End_Position=[];
        app.DisForcedowny_Start_Position=[];
        app.DisForcedowny_End_Position=[];
        app.DisForceupy_Total_Position=[];
        app.DisForcedowny_Total_Position=[];
        app.MomentCCW_Position=[];
        app.MomentCW_Position=[];
        %Deflection
        app.Deflection_Mat=[];
        %Plotting
        app.Beam=[];
        app.Support=[];
        app.Force=[];
        app.Moment=[];
        app.DisForce=[];
        app.Reactions=[];
        app.MFReaction=[];
        % on/off
        app.Pin_is_on=0;
        app.Roller_is_on=0;
        app.Fixed_is_on=0;
        
        
        cla(app.UIAxes);
        cla(app.UIAxes_3);
        cla(app.UIAxes_4);
        cla(app.UIAxes_5);
        legend(app.UIAxes,'off');
        
        app.Ixx_value.Text='_';
        app.Deflection_value.Text='_';

        app.MAX_N.Text='_';
        app.MIN_N.Text='_';
        app.MAX_S.Text='_';
        app.MIN_S.Text='_';
        app.MAX_B.Text='_';
        app.MIN_B.Text='_'; 
        
        
        app.Pin_sup.Text=' ';
        app.Roller_sup.Text=' ';
        app.Pin_pos.Text=' ';
        app.Roller_Pos.Text=' ';
        app.Pin_Fx.Text=' ';
        app.Roller_Fx.Text=' ';
        app.Pin_Fy.Text=' ';
        app.Roller_Fy.Text=' ';
        app.Pin_Mx.Text=' ';
        app.Roller_Mx.Text=' ';
        app.Fixed_sup.Text='Support';
        app.Fixed_pos.Text='_';
        app.Fixed_Fx.Text='_';
        app.Fixed_Fy.Text='_';
        app.Fixed_Mx.Text='_';
        
        app.BeamLengthmEditFieldLabel.Text=['Beam Length (' app.Length_Unit ')'];
        app.PinPositionmEditFieldLabel.Text=['Pin Position (' app.Length_Unit ')'];
        app.RollerPositionmEditFieldLabel.Text=['Roller Position (' app.Length_Unit ')'];
        app.FixedPositionmEditFieldLabel.Text=['Fixed Position (' app.Length_Unit ')'];
        app.PositionmEditField_2Label.Text=['Position (' app.Length_Unit ')'];
        app.MagnitudeNEditFieldLabel.Text=['Magnitude (' app.Force_Unit ')'];
        app.StartPosmEditFieldLabel.Text=['Start Pos (' app.Length_Unit ')'];
        app.EndPosmEditFieldLabel.Text=['End Pos (' app.Length_Unit ')'];
        app.Unit_DisForce.Text=['(' app.DisForce_Unit ')'];
        app.PositionmEditFieldLabel.Text=['Position (' app.Length_Unit ')'];
        app.MagnitudeNmEditFieldLabel.Text=['Magnitude (' app.Moment_Unit ')'];
        app.EGPaEditFieldLabel.Text=['E (' app.E_Unit ')'];
        app.tfmEditFieldLabel.Text=['tf (' app.Length_Unit ')'];
        app.twmEditFieldLabel.Text=['tw (' app.Length_Unit ')'];
        app.bfmEditFieldLabel.Text=['bf (' app.Length_Unit ')'];
        app.dmEditFieldLabel.Text=['d (' app.Length_Unit ')'];        
        app.DeflectionPositionmEditFieldLabel.Text=['Deflection Position (' app.Length_Unit ')'];        
        
        app.NotSolvedLamp.Color='r';
        app.NotSolvedLampLabel.Text='Not Solved';
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {713, 713, 713};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {713, 713};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {408, '1x', 499};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.PinTab = uitab(app.TabGroup);
            app.PinTab.Title = 'Pin';
            app.Pin_add = uibutton(app.PinTab, 'push');
            app.Pin_add.ButtonPushedFcn = createCallbackFcn(app, @Pin_addPushed, true);
            app.Pin_add.Text = 'add';
            app.PinPositionmEditFieldLabel = uilabel(app.PinTab);
            app.PinPositionmEditFieldLabel.Text = 'Pin Position (m)';
            app.PinPositionmEditField = uieditfield(app.PinTab, 'numeric');
            app.Image4 = uiimage(app.PinTab);
            app.Image4.ImageSource = 'Pin.PNG';
            app.RollerTab = uitab(app.TabGroup);
            app.RollerTab.Title = 'Roller';
            app.RollerPositionmEditFieldLabel = uilabel(app.RollerTab);
            app.RollerPositionmEditFieldLabel.Text = 'Roller Position (m)';
            app.RollerPositionmEditField = uieditfield(app.RollerTab, 'numeric');
            app.Roller_add = uibutton(app.RollerTab, 'push');
            app.Roller_add.ButtonPushedFcn = createCallbackFcn(app, @Roller_addPushed, true);
            app.Roller_add.Text = 'add';
            app.Image4_2 = uiimage(app.RollerTab);
            app.Image4_2.ImageSource = 'Roller.PNG';
            app.FixedTab = uitab(app.TabGroup);
            app.FixedTab.Title = 'Fixed';
            app.FixedPositionmEditFieldLabel = uilabel(app.FixedTab);
            app.FixedPositionmEditFieldLabel.Text = 'Fixed Position (m)';
            app.FixedPositionmEditField = uieditfield(app.FixedTab, 'numeric');
            app.Fixed_add = uibutton(app.FixedTab, 'push');
            app.Fixed_add.ButtonPushedFcn = createCallbackFcn(app, @Fixed_addPushed, true);
            app.Fixed_add.Text = 'add';
            app.Image4_3 = uiimage(app.FixedTab);
            app.Image4_3.ImageSource = 'Fixed.PNG';
            app.TabGroup2 = uitabgroup(app.LeftPanel);
            app.PointLoadsTab = uitab(app.TabGroup2);
            app.PointLoadsTab.Title = 'Point Loads';
            app.Image = uiimage(app.PointLoadsTab);
            app.Image.ImageSource = 'arrow-up-black-up-arrow-115632617737cvxxumden.png';
            app.Image2 = uiimage(app.PointLoadsTab);
            app.Image2.ImageSource = 'Arrow_up_right-512.png';
            app.Image3 = uiimage(app.PointLoadsTab);
            app.Image3.ImageSource = 'down-arrow-black-arrow-down-11562910488ntpji5lifi.png';
            app.MagnitudeNEditFieldLabel = uilabel(app.PointLoadsTab);
            app.MagnitudeNEditFieldLabel.Text = 'Magnitude (N)';
            app.MagnitudeNEditField = uieditfield(app.PointLoadsTab, 'numeric');
            app.AngleDegEditFieldLabel = uilabel(app.PointLoadsTab);
            app.AngleDegEditFieldLabel.Text = 'Angle (Deg) ';
            app.AngleDegEditField = uieditfield(app.PointLoadsTab, 'numeric');
            app.Point_load_down = uibutton(app.PointLoadsTab, 'push');
            app.Point_load_down.ButtonPushedFcn = createCallbackFcn(app, @Point_load_downPushed, true);
            app.Point_load_down.Text = 'add';
            app.Point_load_up = uibutton(app.PointLoadsTab, 'push');
            app.Point_load_up.ButtonPushedFcn = createCallbackFcn(app, @Point_load_upPushed, true);
            app.Point_load_up.Text = 'add';
            app.Point_load_angle = uibutton(app.PointLoadsTab, 'push');
            app.Point_load_angle.ButtonPushedFcn = createCallbackFcn(app, @Point_load_anglePushed, true);
            app.Point_load_angle.Text = 'add';
            app.PositionmEditField_2Label = uilabel(app.PointLoadsTab);
            app.PositionmEditField_2Label.Text = 'Position (m)';
            app.PositionmEditField_2 = uieditfield(app.PointLoadsTab, 'numeric');
            app.DistributedLoadsTab = uitab(app.TabGroup2);
            app.DistributedLoadsTab.Title = 'Distributed Loads';
            app.Image3_2 = uiimage(app.DistributedLoadsTab);
            app.Image3_2.ImageSource = '281-2819740_1-to-3-arrows-three-arrows-pointing-down.png';
            app.Image_2 = uiimage(app.DistributedLoadsTab);
            app.Image_2.ImageSource = '281-2819740_1-to-3-arrows-three-arrows-pointing-down - Copy.png';
            app.Dist_load_down = uibutton(app.DistributedLoadsTab, 'push');
            app.Dist_load_down.ButtonPushedFcn = createCallbackFcn(app, @Dist_load_downPushed, true);
            app.Dist_load_down.Text = 'add';
            app.Dist_load_up = uibutton(app.DistributedLoadsTab, 'push');
            app.Dist_load_up.ButtonPushedFcn = createCallbackFcn(app, @Dist_load_upButtonPushed, true);
            app.Dist_load_up.Text = 'add';
            app.StartPosmEditFieldLabel = uilabel(app.DistributedLoadsTab);
            app.StartPosmEditFieldLabel.Text = 'Start Pos (m)';
            app.StartPosmEditField = uieditfield(app.DistributedLoadsTab, 'numeric');
            app.EndPosmEditFieldLabel = uilabel(app.DistributedLoadsTab);
            app.EndPosmEditFieldLabel.Text = 'End Pos (m)';
            app.EndPosmEditField = uieditfield(app.DistributedLoadsTab, 'numeric');
            app.WxLabel = uilabel(app.DistributedLoadsTab);
            app.WxLabel.Text = 'W(x) =';
            app.distributed_equation = uieditfield(app.DistributedLoadsTab, 'text');
            app.Unit_DisForce = uilabel(app.DistributedLoadsTab);
            app.Unit_DisForce.Text = '(N/m)';
            app.MomentsTab = uitab(app.TabGroup2);
            app.MomentsTab.Title = 'Moments';
            app.Moment_CW = uibutton(app.MomentsTab, 'push');
            app.Moment_CW.ButtonPushedFcn = createCallbackFcn(app, @Moment_CWButtonPushed, true);
            app.Moment_CW.Text = 'add';
            app.PositionmEditFieldLabel = uilabel(app.MomentsTab);
            app.PositionmEditFieldLabel.Text = 'Position (m)';
            app.PositionmEditField = uieditfield(app.MomentsTab, 'numeric');
            app.MagnitudeNmEditFieldLabel = uilabel(app.MomentsTab);
            app.MagnitudeNmEditFieldLabel.Text = 'Magnitude (N.m)';
            app.MagnitudeNmEditField = uieditfield(app.MomentsTab, 'numeric');
            app.Moment_CW_im = uiimage(app.MomentsTab);
            app.Moment_CW_im.ImageSource = '67-672145_rotating-arrow-png-rotating-arrow-png-transparent-png.png';
            app.Moment_CCW = uibutton(app.MomentsTab, 'push');
            app.Moment_CCW.ButtonPushedFcn = createCallbackFcn(app, @Moment_CCWButtonPushed, true);
            app.Moment_CCW.Text = 'add';
            app.Moment_CCW_im = uiimage(app.MomentsTab);
            app.Moment_CCW_im.ImageSource = '6882834_preview.png';
            app.BeamLengthmEditFieldLabel = uilabel(app.LeftPanel);
            app.BeamLengthmEditFieldLabel.Text = 'Beam Length (m)';
            app.BeamLengthmEditField = uieditfield(app.LeftPanel, 'numeric');
            app.Beam_add = uibutton(app.LeftPanel, 'push');
            app.Beam_add.ButtonPushedFcn = createCallbackFcn(app, @Beam_addPushed, true);
            app.Beam_add.Text = 'add';
            app.SupportsLabel = uilabel(app.LeftPanel);
            app.SupportsLabel.Text = 'Supports';
            app.LoadsMomentsLabel = uilabel(app.LeftPanel);
            app.LoadsMomentsLabel.Text = 'Loads & Moments';
            app.UnitsLengthForceMomentLabel = uilabel(app.LeftPanel);
            app.UnitsLengthForceMomentLabel.Text = 'Units (Length - Force - Moment)';
            app.UnitsLengthForceMomentDropDown = uidropdown(app.LeftPanel);
            app.UnitsLengthForceMomentDropDown.Items = {'m - N - N.m', 'cm - N - N.cm', 'm - kN - kN.m', 'ft - lbf - lbf.ft', 'in - lbf - lbf.in'};
            app.UnitsLengthForceMomentDropDown.Value = 'm - N - N.m';
            app.Unit_add = uibutton(app.LeftPanel, 'push');
            app.Unit_add.ButtonPushedFcn = createCallbackFcn(app, @Unit_addButtonPushed, true);
            app.Unit_add.Text = 'add';
            app.EGPaEditFieldLabel = uilabel(app.LeftPanel);
            app.EGPaEditFieldLabel.Text = 'E (GPa)';
            app.EGPaEditField = uieditfield(app.LeftPanel, 'numeric');
            app.E_add = uibutton(app.LeftPanel, 'push');
            app.E_add.ButtonPushedFcn = createCallbackFcn(app, @E_addButtonPushed, true);
            app.E_add.Text = 'add';
            app.TabGroup3 = uitabgroup(app.LeftPanel);
            app.IxxTab = uitab(app.TabGroup3);
            app.IxxTab.Title = 'Ixx';
            app.Image5 = uiimage(app.IxxTab);
            app.Image5.ImageSource = 'I_beam.png';
            app.tfmEditFieldLabel = uilabel(app.IxxTab);
            app.tfmEditFieldLabel.Text = 'tf (m)';
            app.tfmEditField = uieditfield(app.IxxTab, 'numeric');
            app.bfmEditFieldLabel = uilabel(app.IxxTab);
            app.bfmEditFieldLabel.Text = 'bf (m)';
            app.bfmEditField = uieditfield(app.IxxTab, 'numeric');
            app.twmEditFieldLabel = uilabel(app.IxxTab);
            app.twmEditFieldLabel.Text = 'tw (m)';
            app.twmEditField = uieditfield(app.IxxTab, 'numeric');
            app.dmEditFieldLabel = uilabel(app.IxxTab);
            app.dmEditFieldLabel.Text = 'd (m)';
            app.dmEditField = uieditfield(app.IxxTab, 'numeric');
            app.Ibeam_add = uibutton(app.IxxTab, 'push');
            app.Ibeam_add.ButtonPushedFcn = createCallbackFcn(app, @Ibeam_addButtonPushed, true);
            app.Ibeam_add.Text = 'add';
            app.IxxLabel = uilabel(app.IxxTab);
            app.IxxLabel.Text = 'Ixx=';
            app.Ixx_value = uilabel(app.IxxTab);
            app.Ixx_value.Text = '__';
            app.IbeamLabel = uilabel(app.LeftPanel);
            app.IbeamLabel.Text = 'I beam';
            app.CenterPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.CenterPanel);
            title(app.UIAxes, 'Beam')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = '';
            app.BeamAnalysisLabel = uilabel(app.CenterPanel);
            app.BeamAnalysisLabel.Text = 'Beam Analysis';
            app.SolveButton = uibutton(app.CenterPanel, 'push');
            app.SolveButton.ButtonPushedFcn = createCallbackFcn(app, @SolveButtonPushed, true);
            app.SolveButton.Text = 'Solve';
            app.NotSolvedLampLabel = uilabel(app.CenterPanel);
            app.NotSolvedLampLabel.Text = 'Not Solved';
            app.NotSolvedLamp = uilamp(app.CenterPanel);
            app.ReactionsPanel = uipanel(app.CenterPanel);
            app.ReactionsPanel.Title = 'Reactions';
            app.React_XLabel = uilabel(app.ReactionsPanel);
            app.React_XLabel.Text = 'React_X';
            app.React_YLabel = uilabel(app.ReactionsPanel);
            app.React_YLabel.Text = 'React_Y';
            app.React_MxLabel = uilabel(app.ReactionsPanel);
            app.React_MxLabel.Text = 'React_Mx';
            app.Fixed_sup = uilabel(app.ReactionsPanel);
            app.Fixed_sup.Text = 'Support';
            app.PositionLabel_2 = uilabel(app.ReactionsPanel);
            app.PositionLabel_2.Text = 'Position';
            app.Fixed_pos = uilabel(app.ReactionsPanel);
            app.Fixed_pos.Text = '_';
            app.Fixed_Fx = uilabel(app.ReactionsPanel);
            app.Fixed_Fx.Text = '_';
            app.Fixed_Fy = uilabel(app.ReactionsPanel);
            app.Fixed_Fy.Text = '_';
            app.Fixed_Mx = uilabel(app.ReactionsPanel);
            app.Fixed_Mx.Text = '_';
            app.Pin_sup = uilabel(app.ReactionsPanel);
            app.Pin_sup.Text = '';
            app.Roller_sup = uilabel(app.ReactionsPanel);
            app.Roller_sup.Text = '';
            app.Pin_pos = uilabel(app.ReactionsPanel);
            app.Pin_pos.Text = '';
            app.Roller_Pos = uilabel(app.ReactionsPanel);
            app.Roller_Pos.Text = '';
            app.Pin_Fx = uilabel(app.ReactionsPanel);
            app.Pin_Fx.Text = '';
            app.Roller_Fx = uilabel(app.ReactionsPanel);
            app.Roller_Fx.Text = '';
            app.Pin_Fy = uilabel(app.ReactionsPanel);
            app.Pin_Fy.Text = '';
            app.Roller_Fy = uilabel(app.ReactionsPanel);
            app.Roller_Fy.Text = '';
            app.Pin_Mx = uilabel(app.ReactionsPanel);
            app.Pin_Mx.Text = '';
            app.Roller_Mx = uilabel(app.ReactionsPanel);
            app.Roller_Mx.Text = '';
            app.ForceExtremesPanel = uipanel(app.CenterPanel);
            app.ForceExtremesPanel.Title = 'Force Extremes';
            app.NormalForceLabel = uilabel(app.ForceExtremesPanel);
            app.NormalForceLabel.Text = 'Normal Force';
            app.ShearForceLabel = uilabel(app.ForceExtremesPanel);
            app.ShearForceLabel.Text = 'Shear Force';
            app.BendingMomentLabel = uilabel(app.ForceExtremesPanel);
            app.BendingMomentLabel.Text = 'Bending Moment';
            app.MAXLabel = uilabel(app.ForceExtremesPanel);
            app.MAXLabel.Text = 'MAX';
            app.MINLabel = uilabel(app.ForceExtremesPanel);
            app.MINLabel.Text = 'MIN';
            app.MAX_N = uilabel(app.ForceExtremesPanel);
            app.MAX_N.Text = '_';
            app.MIN_N = uilabel(app.ForceExtremesPanel);
            app.MIN_N.Text = '_';
            app.MAX_S = uilabel(app.ForceExtremesPanel);
            app.MAX_S.Text = '_';
            app.MIN_S = uilabel(app.ForceExtremesPanel);
            app.MIN_S.Text = '_';
            app.MAX_B = uilabel(app.ForceExtremesPanel);
            app.MAX_B.Text = '_';
            app.MIN_B = uilabel(app.ForceExtremesPanel);
            app.MIN_B.Text = '_';
            app.ClearButton = uibutton(app.CenterPanel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.DeflectionPositionmEditFieldLabel = uilabel(app.CenterPanel);
            app.DeflectionPositionmEditFieldLabel.Text = 'Deflection Position (m)';
            app.DeflectionPositionmEditField = uieditfield(app.CenterPanel, 'numeric');
            app.Deflection = uibutton(app.CenterPanel, 'push');
            app.Deflection.ButtonPushedFcn = createCallbackFcn(app, @DeflectionButtonPushed, true);
            app.Deflection.Text = 'add';
            app.Deflection_value = uilabel(app.CenterPanel);
            app.Deflection_value.Text = '__';
            app.DeflectionLabel = uilabel(app.CenterPanel);
            app.DeflectionLabel.Text = 'Deflection=';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes_3 = uiaxes(app.RightPanel);
            title(app.UIAxes_3, 'Shear Force')
            ylabel(app.UIAxes_3, 'V')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_4 = uiaxes(app.RightPanel);
            title(app.UIAxes_4, 'Bending Moment')
            ylabel(app.UIAxes_4, 'M')
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_5 = uiaxes(app.RightPanel);
            title(app.UIAxes_5, 'Deflection')
            ylabel(app.UIAxes_5, 'v')
            zlabel(app.UIAxes_5, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Mechanics_of_Materials_2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end